classdef qBRM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        MaxintensitySpinner             matlab.ui.control.Spinner
        MaxintensityLabel               matlab.ui.control.Label
        FinddiameterofisolatedvesiclesPanel  matlab.ui.container.Panel
        DrawingmodeButton               matlab.ui.control.StateButton
        VesiclediamemterumEditField     matlab.ui.control.EditField
        VesiclediamemterumEditFieldLabel  matlab.ui.control.Label
        UIAxes3                         matlab.ui.control.UIAxes
        FileIOPanel                     matlab.ui.container.Panel
        FinishSaveButton                matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        ZstackTab                       matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        FocusstackTab                   matlab.ui.container.Tab
        UIAxesDS                        matlab.ui.control.UIAxes
        FramesSlider                    matlab.ui.control.Slider
        FramesSliderLabel               matlab.ui.control.Label
        FindslopeofaxonalabnormalitiesPanel  matlab.ui.container.Panel
        DrawingmodeButton2              matlab.ui.control.StateButton
        LengthofeachstepEditField       matlab.ui.control.NumericEditField
        LengthofeachstepEditFieldLabel  matlab.ui.control.Label
        stepsEditField                  matlab.ui.control.NumericEditField
        stepsEditFieldLabel             matlab.ui.control.Label
        RatioEditField                  matlab.ui.control.EditField
        RatioEditFieldLabel             matlab.ui.control.Label
        MaximumdiameterumEditField      matlab.ui.control.EditField
        MaximumdiameterumEditFieldLabel  matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        FindmyelinabnormalitiesPanel    matlab.ui.container.Panel
        myelinabnormalitiesEditField    matlab.ui.control.NumericEditField
        myelinabnormalitiesEditFieldLabel  matlab.ui.control.Label
        DrawingmodeButton3              matlab.ui.control.StateButton
        LoadqBRMdataButton              matlab.ui.control.Button
    end

    
    properties (Access = private)

    end
    
    properties (Access = public)
        output = {};            % output of the app
        index = 0;              % ROI index for vesicles
        index2 = 0;             % ROI index for balloons
        index3 = 0;             % ROI index for myelin pathologies
        roi_line_balloon        % line ROI of balloons
        roi_line_vesicle        % line ROI of vesicles
        roi_line_myelin         % line ROI of myelin pathologies
        image                   % the image data
        c                       % line profile
        image_DS                % defocused stack of the image data
        RGB_img                 % RGB image of the segmentation
        v_diameter              % diameter of vesicle
        diameter                % diameter along the balloon
        balloon_ratio           % ratio of balloon
        myelin_count            % number of myelin pathologies
        hLine_vesicle           % current vesicle ROI
        hLine_balloon           % current balloon ROI
        hLine_myelin            % current myelin ROI
        startline = {};         % starting perpendicular line of the balloon
        endline = {};           % ending perpendicular line of the balloon
        l1                      % listener for moving the ROI
        l2                      % listener for deleting the ROI
        l3                      % listener for double-clicking the ROI
        l4                      % listener for moving the ROI
        l5                      % listener for deleting the ROI
        l6                      % listener for double-clicking the ROI
        l7                      % listener for moving the ROI
        l8                      % listener for deleting the ROI
        l9                      % listener for double-clicking the ROI
        fDS                     % the ROI drawing figure
        xx                      % x axis for interpolation
        c_interp                % interpolation line profile
        tilename                % name of the image tile
        direction               % direction of balloon central line
    end
    
    methods (Access = public)
        
        function overlay_ROI(app)       % draw ROI on UIAxes
            cla(app.UIAxesDS);
            app.fDS = imagesc(app.UIAxesDS,mat2gray(app.image_DS));
            if ~isempty(app.roi_line_vesicle)
                for i=1:length(app.roi_line_vesicle)
                    line_position = app.roi_line_vesicle{i};
                    if ~isempty(line_position)
                        drawline(app.UIAxesDS,'Color','r','Position',line_position,'LineWidth',1,'Label',num2str(i),'LabelAlpha',0.2,'LabelTextColor','r');  
                        drawline(app.UIAxes,'Color','r','Position',line_position,'LineWidth',1,'Label',num2str(i),'LabelAlpha',0.2,'LabelTextColor','r');
                    end
                end
            end
            if ~isempty(app.roi_line_balloon)
                for i=1:length(app.roi_line_balloon)
                    line_position = app.roi_line_balloon{i};
                    if ~isempty(line_position)
                        drawline(app.UIAxes,'Color','b','Position',line_position,'LineWidth',1,'Label',num2str(i),'LabelAlpha',0.2,'LabelTextColor','b');
                        drawline(app.UIAxesDS,'Color','b','Position',line_position,'LineWidth',1,'Label',num2str(i),'LabelAlpha',0.2,'LabelTextColor','b');
                    end
                end
            end
            if ~isempty(app.roi_line_myelin)
                for i=1:length(app.roi_line_myelin)
                    line_position = app.roi_line_myelin{i};
                    if ~isempty(line_position)
                        drawline(app.UIAxes,'Color','g','Position',line_position,'LineWidth',1,'Label',num2str(i),'LabelAlpha',0.2,'LabelTextColor','g');
                        drawline(app.UIAxesDS,'Color','g','Position',line_position,'LineWidth',1,'Label',num2str(i),'LabelAlpha',0.2,'LabelTextColor','g');
                    end
                end
            end
        end
                          
        function roiallevents(app,~,evt)
                evname = evt.EventName;
                sl = str2double(evt.Source.Label);
                if strcmp(evname,'ROIMoved')
                    if ~isempty(app.hLine_vesicle{sl})
                        app.c = improfile(squeeze(app.image(app.FramesSlider.Value,:,:)),[app.hLine_vesicle{sl}.Position(1,1) app.hLine_vesicle{sl}.Position(2,1)],...
                            [app.hLine_vesicle{sl}.Position(1,2) app.hLine_vesicle{sl}.Position(2,2)]);
                        % normalize the line profile to remove background
                        app.c = app.c - min(app.c);
                        % interpolize the data
                        app.xx = 1:0.01:length(app.c);
                        app.c_interp = spline(1:length(app.c),app.c,app.xx);
                        plot(app.UIAxes3,app.xx,app.c_interp,'r-');
                        app.UIAxes3.XTick = linspace(1,length(app.c),11);
                        app.UIAxes3.XTickLabel = linspace(0,pdist(app.hLine_vesicle{sl}.Position,'euclidean'),11);
                    end
                elseif strcmp(evname,'DeletingROI')
                    delete(app.l2{sl});
                    delete(app.l1{sl});
                    delete(app.l3{sl});
                    app.DrawingmodeButton.Value=0;
                elseif strcmp(evname,'ROIClicked') && strcmp(evt.SelectionType,'double')
                    if ~isempty(app.hLine_vesicle{sl})
                        % calculate the G-ratio based on the line profile
                        [~,locs,~,~] = findpeaks(app.c_interp,app.xx,'SortStr',"descend",'MinPeakDistance',3,'WidthReference',"halfheight");
                        if length(locs) >= 2
                            % find the distance between the peaks
                            app.v_diameter(sl)=0.325*(abs(locs(1)-locs(2)));
                            app.VesiclediamemterumEditField.Value = num2str(app.v_diameter(sl));
                            app.UIAxes.NextPlot = 'add';
                            plot(app.UIAxes,app.hLine_vesicle{sl}.Position(:,1),app.hLine_vesicle{sl}.Position(:,2),'LineWidth',3,'Color','r');
                            app.roi_line_vesicle{sl} = app.hLine_vesicle{sl}.Position;
                        else
                            msgbox('Only 1 peak detected. Please re-select data!');
                        end
                    end
                    delete(app.l1{sl});
                    delete(app.l2{sl});
                    delete(app.l3{sl});
                    app.DrawingmodeButton.Value=0;
                end
           end

           function roiallevents2(app,~,evt)
                evname = evt.EventName;
                sl = str2double(evt.Source.Label);
                if strcmp(evname,'ROIMoved')
                    delete(app.startline);
                    delete(app.endline);
                    app.diameter{app.index2}=[];
                    if ~isempty(app.hLine_balloon{sl})
                        % find ROI positions
                        start_point = [app.hLine_balloon{sl}.Position(1,1) app.hLine_balloon{sl}.Position(1,2)];
                        end_point = [app.hLine_balloon{sl}.Position(2,1) app.hLine_balloon{sl}.Position(2,2)];
                        % find the segment for ratio calculation
                        app.direction = end_point - start_point;
                        % define number of steps
                        if isempty(app.stepsEditField.Value)
                            steps = 10;
                        else
                            steps = app.stepsEditField.Value;
                        end
                        pscan = linspace(0,1,steps+2);
                        pscan = pscan(2:end-1);
                        x = start_point(1) + pscan*app.direction(1);
                        y = start_point(2) + pscan*app.direction(2);
                        % define the length for each step
                        if isempty(app.LengthofeachstepEditField.Value)
                            length_steps = 25;
                        else
                            length_steps = app.LengthofeachstepEditField.Value;
                        end
                        v = length_steps*app.direction/norm(app.direction);
                        % plot the segments on figure
                        for pseg = 1:length(pscan)
                            seg_start = [x(pseg)+v(2), x(pseg)-v(2)];
                            seg_end = [y(pseg)-v(1), y(pseg)+v(1)];
                            seg_int = improfile(app.image_DS,seg_start,seg_end);
                            app.xx = 1:0.01:length(seg_int);
                            app.c_interp = spline(1:length(seg_int),seg_int,app.xx);
                            [~,locs,~,~] = findpeaks(app.c_interp,app.xx,'SortStr',"descend",'MinPeakDistance',4,'WidthReference',"halfheight");
                            if length(locs) >= 2
                                app.diameter{app.index2}(pseg) = abs(locs(1)-locs(2));
                            elseif pseg >1
                                app.diameter{app.index2}(pseg) = app.diameter{app.index2}(pseg-1);
                            else
                                app.diameter{app.index2}(pseg) = 0;
                            end
                            if pseg == 1
                                app.startline=line(app.UIAxesDS,seg_start,seg_end,'LineWidth',1);
                            end
                            if pseg == length(pscan)
                                app.endline=line(app.UIAxesDS,seg_start,seg_end,'LineWidth',1);
                            end
                        end
                        % calculate the width for each line segments
                        plot(app.UIAxes2,1:length(app.diameter{app.index2}),app.diameter{app.index2},'b-');
                        app.UIAxes2.YLim = ([0 round(max(app.diameter{app.index2}))+2]);
                    end
                elseif strcmp(evname,'DeletingROI')
                    delete(app.l4{sl});
                    delete(app.l5{sl});
                    delete(app.l6{sl});
                    app.DrawingmodeButton2.Value=0;
                elseif strcmp(evname,'ROIClicked') && strcmp(evt.SelectionType,'double')
                    if ~isempty(app.hLine_balloon{sl})
                        hLine2 = drawline(app.UIAxes,'Color','b','Position',app.hLine_balloon{sl}.Position);
                        linkprop([app.hLine_balloon{sl} hLine2],'Position');
                        app.MaximumdiameterumEditField.Value = num2str(max(app.diameter{app.index2})*0.325);
                        app.balloon_ratio(app.index2) = max(app.diameter{app.index2})/mean(app.diameter{app.index2});
                        app.RatioEditField.Value = num2str(app.balloon_ratio);
                        app.UIAxesDS.NextPlot = 'add';
                        plot(app.UIAxesDS,app.hLine_balloon{sl}.Position(:,1),app.hLine_balloon{sl}.Position(:,2),'LineWidth',3,'Color','b');
                        app.roi_line_balloon{app.index2} = app.hLine_balloon{sl}.Position;
                    end                    
                    delete(app.l4{sl});
                    delete(app.l5{sl});
                    delete(app.l6{sl});
                    app.DrawingmodeButton2.Value=0;
                end
           end
           
           function roiallevents3(app,~,evt)
                evname = evt.EventName;
                sl = str2double(evt.Source.Label);
                if strcmp(evname,'ROIMoved')
                    
                elseif strcmp(evname,'DeletingROI')
                    delete(app.l7{sl});
                    delete(app.l8{sl});
                    delete(app.l9{sl});
                    app.DrawingmodeButton3.Value=0;
                elseif strcmp(evname,'ROIClicked') && strcmp(evt.SelectionType,'double')
                    if ~isempty(app.hLine_myelin{sl})
                        app.myelin_count = app.myelin_count + 1;
                        app.myelinabnormalitiesEditField.Value = app.myelin_count;
                        app.roi_line_myelin{sl} = app.hLine_myelin{sl}.Position;
                        app.UIAxesDS.NextPlot = 'add';
                        plot(app.UIAxesDS,app.hLine_myelin{sl}.Position(:,1),app.hLine_myelin{sl}.Position(:,2),'LineWidth',3,'Color','g');
                    end
                    delete(app.l7{sl});
                    delete(app.l8{sl});
                    delete(app.l9{sl});
                    app.DrawingmodeButton3.Value=0;
                end
           end
           
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadqBRMdataButton
        function LoadqBRMdataButtonPushed(app, event)
           
           clc;
           addpath(genpath(pwd));
           
           % re-initialize all the parameters
           app.index = 0;
           app.index2 = 0;
           app.index3 = 0;
           app.roi_line_vesicle = {};
           app.roi_line_balloon = {};
           app.roi_line_myelin = {};
           app.v_diameter = [];
           app.balloon_ratio = [];
           app.myelin_count = 0;
           app.diameter = {};
           app.myelinabnormalitiesEditField.Value = 0;
           
           
           % re-initialize all the UI axes
           cla(app.UIAxes2);
           cla(app.UIAxes3);
           cla(app.UIAxes);
           cla(app.UIAxesDS);
           title(app.UIAxes, []);
           xlabel(app.UIAxes, []);
           ylabel(app.UIAxes, []);
           app.UIAxes.XAxis.TickLabels = {};
           app.UIAxes.YAxis.TickLabels = {};
           title(app.UIAxesDS, []);
           xlabel(app.UIAxesDS, []);
           ylabel(app.UIAxesDS, []);
           app.UIAxesDS.XAxis.TickLabels = {};
           app.UIAxesDS.YAxis.TickLabels = {};
           xlabel(app.UIAxes2, 'central line length (pxl)');
           ylabel(app.UIAxes2, 'diameter of the myelin (pxl)');
           xlabel(app.UIAxes3, 'central line length (pxl)');
           ylabel(app.UIAxes3, 'diameter of the vesicle (pxl)');
           
           % randomly read a tile from the directory
           filename = dir('.\data\*.tif');
           
           if ~exist([pwd 'annotated'],'dir')
               mkdir annotated;
           end


           if isempty(filename)
               msgbox('Image file is missing in this folder! Check directory and reload.');
           else
               n_file = length(filename);
               file_id = randi(n_file,1);
               app.tilename = filename(file_id).name;
               info = imfinfo(['.\data\' app.tilename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread(['.\data\' app.tilename],1);
                       app.image = zeros([length(info) size(temp)]);
                       app.image(u,:,:) = temp;
                   else
                       app.image(u,:,:) = imread(['.\data\' app.tilename],u);
                   end
               end
               if length(size(app.image))>2
                   f = imagesc(app.UIAxes,mat2gray(squeeze(app.image(1,:,:))));
                   app.UIAxes.Colormap = gray;
                   % Set limits of axes
                   app.UIAxes.XLim = [0 f.XData(2)];
                   app.UIAxes.YLim = [0 f.YData(2)];
                   app.UIAxes.CLim = [0 0.5];
                   app.FramesSlider.Limits = [1 size(app.image,1)];
                   app.FramesSlider.MinorTicks = [];
                   app.FramesSlider.Value = 1;
                   % set colormap limit
                   app.MaxintensitySpinner.Value = 25;
                   % generate focusing stack
                   h = waitbar(0,'Generating defocused stack ...');
                   app.image_DS = fstack(squeeze(num2cell(uint16(permute(app.image, [2 3 1])),[1 2])),...
                       'dilatesize', 9,'blendsize', 9);
                   waitbar(1,h,'Finished.');
                   close(h);
                   app.fDS = imagesc(app.UIAxesDS,mat2gray(app.image_DS));
                   app.UIAxesDS.Colormap = gray;
                   % Set limits of axes
                   app.UIAxesDS.XLim = [0 app.fDS.XData(2)];
                   app.UIAxesDS.YLim = [0 app.fDS.YData(2)];
                   app.UIAxesDS.CLim = [0 0.25];
               else
                   f = imagesc(app.UIAxes,mat2gray(app.image_DS));
                   app.UIAxes.Colormap = gray;
                   % Set limits of axes
                   app.UIAxes.XLim = [0 f.XData(2)];
                   app.UIAxes.YLim = [0 f.YData(2)];
                   app.UIAxes.CLim = [0 0.25];
                   % read color limit
                   app.MaxintensitySpinner.Value = 7500;
                   app.fDS = imagesc(app.UIAxesDS,mat2gray(app.image));
                   app.UIAxesDS.Colormap = gray;
                   % Set limits of axes
                   app.UIAxesDS.XLim = [0 app.fDS.XData(2)];
                   app.UIAxesDS.YLim = [0 app.fDS.YData(2)];
                   app.UIAxesDS.CLim = [0 0.5];
               end
           end          
        end

        % Value changed function: FramesSlider
        function FramesSliderValueChanged(app, event)
            if ~isempty(app.image)
                value = round(app.FramesSlider.Value);
                app.FramesSlider.Value = value;
                app.UIAxes.NextPlot = 'replacechildren';
                f = imagesc(app.UIAxes,mat2gray(squeeze(app.image(value,:,:))));
                app.UIAxes.Colormap = gray;
                % Set limits of axes
                app.UIAxes.XLim = [0 f.XData(2)];
                app.UIAxes.YLim = [0 f.YData(2)];
                app.UIAxes.CLim = [0 app.MaxintensitySpinner.Value/100];
                overlay_ROI(app);
            else
                msgbox('Load image file first!');
            end
        end

        % Value changing function: FramesSlider
        function FramesSliderValueChanging(app, event)
            if ~isempty(app.image)
                changingValue = event.Value;
                cvalue = round(changingValue);
                app.UIAxes.NextPlot = 'replacechildren';
                f = imagesc(app.UIAxes,mat2gray(squeeze(app.image(cvalue,:,:))));
                app.UIAxes.Colormap = gray;
                % Set limits of axes
                app.UIAxes.XLim = [0 f.XData(2)];
                app.UIAxes.YLim = [0 f.YData(2)];
                app.UIAxes.CLim = [0 app.MaxintensitySpinner.Value/100];
                overlay_ROI(app);
            end
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            % verticalScrollAmount = event.VerticalScrollAmount;
            verticalScrollCount = event.VerticalScrollCount;
            if ~isempty(app.image)
                if verticalScrollCount > 0
                    value = app.FramesSlider.Value;
                    if value < app.FramesSlider.Limits(2)
                        app.UIAxes.NextPlot = 'replacechildren';
                        f = imagesc(app.UIAxes,mat2gray(squeeze(app.image(value+1,:,:))));
                        app.FramesSlider.Value = value +1;
                        app.UIAxes.Colormap = gray;
                        % Set limits of axes
                        app.UIAxes.XLim = [0 f.XData(2)];
                        app.UIAxes.YLim = [0 f.YData(2)];
                        app.UIAxes.CLim = [0 app.MaxintensitySpinner.Value/100];
                        overlay_ROI(app);
                    end
                elseif verticalScrollCount < 0
                    value = app.FramesSlider.Value;
                    if value > app.FramesSlider.Limits(1)
                        app.UIAxes.NextPlot = 'replacechildren';
                        f = imagesc(app.UIAxes,mat2gray(squeeze(app.image(value-1,:,:))));
                        app.FramesSlider.Value = value -1;
                        app.UIAxes.Colormap = gray;
                        % Set limits of axes
                        app.UIAxes.XLim = [0 f.XData(2)];
                        app.UIAxes.YLim = [0 f.YData(2)];
                        app.UIAxes.CLim = [0 app.MaxintensitySpinner.Value/100];
                        overlay_ROI(app);
                    end
                end
            end
        end

        % Button pushed function: FinishSaveButton
        function FinishSaveButtonPushed(app, event)
            % save the counting numbers and the abnormalities features
            app.output.vesicle = app.roi_line_vesicle;
            app.output.balloon = app.roi_line_balloon;
            app.output.myelin = app.roi_line_myelin;
            app.output.vesicle_diameter = app.v_diameter;
            app.output.balloon_raio = app.balloon_ratio;
            app.output.balloon_diameter = app.diameter;
            app.output.myelin_count = app.myelin_count;
            UIoutput = app.output;
            if isempty(app.roi_line_balloon) && isempty(app.roi_line_vesicle) && (app.myelin_count == 0)
                answer = questdlg('No abnormalities is labeled! Do you want to save?','No abnormalities labeled','Yes','No','No');
                if strcmp(answer,'Yes')
                        tile_seg = strsplit(app.tilename,'.');
                        tile_id = tile_seg{1};
                        movefile(['.\data\' app.tilename], '.\annotated');
                        save(['.\annotated\',tile_id,'.mat'],'UIoutput','-mat');
                        msgbox('Output saved!')
                end
            else
                tile_seg = strsplit(app.tilename,'.');
                tile_id = tile_seg{1};
                movefile(['.\data\' app.tilename], '.\annotated');
                save(['.\annotated\',tile_id,'.mat'],'UIoutput','-mat');
                msgbox('Output saved!')
            end
        end

        % Value changed function: MaxintensitySpinner
        function MaxintensitySpinnerValueChanged(app, event)
            value = app.MaxintensitySpinner.Value;
            svalue = round(app.FramesSlider.Value);
            app.UIAxes.NextPlot = 'replacechildren';
            f = imagesc(app.UIAxes,mat2gray(squeeze(app.image(svalue,:,:))));
            app.UIAxes.Colormap = gray;
            % Set limits of axes
            app.UIAxes.XLim = [0 f.XData(2)];
            app.UIAxes.YLim = [0 f.YData(2)];
            app.UIAxes.CLim = [0 value/100];
            app.UIAxesDS.CLim = [0 value/100];
            overlay_ROI(app);
        end

        % Value changed function: DrawingmodeButton
        function DrawingmodeButtonValueChanged(app, event)
            value = app.DrawingmodeButton.Value;
            if value == 1    
                app.VesiclediamemterumEditField.Value = '';
                app.index = app.index +1;
                app.hLine_vesicle{app.index} = drawline(app.UIAxes,'LineWidth',1,'Color','r',...
                    'Label',num2str(app.index),'LabelAlpha',0.2,'LabelTextColor','r');             
                if pdist(app.hLine_vesicle{app.index}.Position,'euclidean') > 1 
                    hLine2 = drawline(app.UIAxesDS,'Color','r','Position',app.hLine_vesicle{app.index}.Position);
                    linkprop([app.hLine_vesicle{app.index} hLine2],'Position');
                    app.c = improfile(squeeze(app.image(app.FramesSlider.Value,:,:)),[app.hLine_vesicle{app.index}.Position(1,1) app.hLine_vesicle{app.index}.Position(2,1)],...
                        [app.hLine_vesicle{app.index}.Position(1,2) app.hLine_vesicle{app.index}.Position(2,2)]);
                    % normalize the line profile to remove background
                    app.c = app.c - min(app.c);
                    % interpolize the data
                    app.xx = 1:0.01:length(app.c);
                    app.c_interp = spline(1:length(app.c),app.c,app.xx);
                    plot(app.UIAxes3,app.xx,app.c_interp,'r-');
                    app.UIAxes3.XTick = linspace(1,length(app.c),11);
                    app.UIAxes3.XTickLabel = linspace(0,pdist(app.hLine_vesicle{app.index}.Position,'euclidean'),11);                    
                    % add listeners for adjusting ROI & updating ROI line profile
                    app.l1{app.index} = addlistener(app.hLine_vesicle{app.index},'ROIMoved',@(src,evt)roiallevents(app,app.hLine_vesicle{app.index},evt));
                    app.l2{app.index} = addlistener(app.hLine_vesicle{app.index},'DeletingROI',@(src,evt)roiallevents(app,app.hLine_vesicle{app.index},evt));
                    app.l3{app.index} = addlistener(app.hLine_vesicle{app.index},'ROIClicked',@(src,evt)roiallevents(app,app.hLine_vesicle{app.index},evt));                   
                end                  
            end
        end

        % Value changed function: DrawingmodeButton2
        function DrawingmodeButton2ValueChanged(app, event)
            value = app.DrawingmodeButton2.Value;
            if value ==1
                app.RatioEditField.Value = '';
                app.MaximumdiameterumEditField.Value = '';
                app.index2 = app.index2 + 1;
                app.hLine_balloon{app.index2} = drawline(app.UIAxesDS,'LineWidth',1,'Color','b',...
                    'Label',num2str(app.index2),'LabelAlpha',0.6,'LabelTextColor','b');          
                if pdist(app.hLine_balloon{app.index2}.Position,'euclidean') > 1            
                    % find ROI positions
                    start_point = [app.hLine_balloon{app.index2}.Position(1,1) app.hLine_balloon{app.index2}.Position(1,2)];
                    end_point = [app.hLine_balloon{app.index2}.Position(2,1) app.hLine_balloon{app.index2}.Position(2,2)];
                    % find the segment for ratio calculation
                    app.direction = end_point - start_point;
                    % define number of steps
                    if isempty(app.stepsEditField.Value)
                        steps = 10;
                    else
                        steps = app.stepsEditField.Value;
                    end
                    pscan = linspace(0,1,steps+2);
                    pscan = pscan(2:end-1);
                    x = start_point(1) + pscan*app.direction(1);
                    y = start_point(2) + pscan*app.direction(2);
                    % define the length for each step
                    if isempty(app.LengthofeachstepEditField.Value)
                        length_steps = 25;
                    else
                        length_steps = app.LengthofeachstepEditField.Value;
                    end
                    v = length_steps*app.direction/norm(app.direction);
                    % plot the segments on figure
                    for pseg = 1:length(pscan)
                        seg_start = [x(pseg)+v(2), x(pseg)-v(2)];
                        seg_end = [y(pseg)-v(1), y(pseg)+v(1)];
                        seg_int = improfile(app.image_DS,seg_start,seg_end);
                        app.xx = 1:0.01:length(seg_int);
                        app.c_interp = spline(1:length(seg_int),seg_int,app.xx);
                        [~,locs,~,~] = findpeaks(app.c_interp,app.xx,'SortStr',"descend",'MinPeakDistance',4,'WidthReference',"halfheight");
                        if length(locs) >= 2
                            app.diameter{app.index2}(pseg) = abs(locs(1)-locs(2));
                        elseif pseg >1
                            app.diameter{app.index2}(pseg) = app.diameter{app.index2}(pseg-1);
                        else
                            app.diameter{app.index2}(pseg) = 0;
                        end
                        if pseg == 1
                            app.startline=line(app.UIAxesDS,seg_start,seg_end,'LineWidth',1);
                        end
                        if pseg == length(pscan)
                            app.endline=line(app.UIAxesDS,seg_start,seg_end,'LineWidth',1);
                        end
                    end
                    % calculate the width for each line segments
                    plot(app.UIAxes2,1:length(app.diameter{app.index2}),app.diameter{app.index2},'b-');
                    app.UIAxes2.YLim = ([0 round(max(app.diameter{app.index2}))+2]);
                    % add listeners for adjusting ROI & updating ROI line profile
                    app.l4{app.index2} = addlistener(app.hLine_balloon{app.index2},'ROIMoved',@(src,evt)roiallevents2(app,app.hLine_balloon{app.index2},evt));
                    app.l5{app.index2} = addlistener(app.hLine_balloon{app.index2},'DeletingROI',@(src,evt)roiallevents2(app,app.hLine_balloon{app.index2},evt));
                    app.l6{app.index2} = addlistener(app.hLine_balloon{app.index2},'ROIClicked',@(src,evt)roiallevents2(app,app.hLine_balloon{app.index2},evt));     
                end
            end
        end

        % Value changed function: stepsEditField
        function stepsEditFieldValueChanged(app, event)
            if ~isempty(app.startline)
                app.diameter{app.index2}=[];
                steps = app.stepsEditField.Value;
                start_point = [app.hLine_balloon{app.index2}.Position(1,1) app.hLine_balloon{app.index2}.Position(1,2)];
                end_point = [app.hLine_balloon{app.index2}.Position(2,1) app.hLine_balloon{app.index2}.Position(2,2)];
                app.direction = end_point - start_point;
                pscan = linspace(0,1,steps+2);
                pscan = pscan(2:end-1);
                x = start_point(1) + pscan*app.direction(1);
                y = start_point(2) + pscan*app.direction(2);
                if isempty(app.LengthofeachstepEditField.Value)
                    length_steps = 25;
                else
                    length_steps = app.LengthofeachstepEditField.Value;
                end
                v = length_steps*app.direction/norm(app.direction);
                % plot the segments on figure
                for pseg = 1:length(pscan)
                    seg_start = [x(pseg)+v(2), x(pseg)-v(2)];
                    seg_end = [y(pseg)-v(1), y(pseg)+v(1)];
                    seg_int = improfile(app.image_DS,seg_start,seg_end);
                    app.xx = 1:0.01:length(seg_int);
                    app.c_interp = spline(1:length(seg_int),seg_int,app.xx);
                    [~,locs,~,~] = findpeaks(app.c_interp,app.xx,'SortStr',"descend",'MinPeakDistance',4,'WidthReference',"halfheight");
                    if length(locs) >= 2
                        app.diameter{app.index2}(pseg) = abs(locs(1)-locs(2));
                    elseif pseg >1
                        app.diameter{app.index2}(pseg) = app.diameter{app.index2}(pseg-1);
                    else
                        app.diameter{app.index2}(pseg) = 0;
                    end
                end
                % calculate the width for each line segments
                plot(app.UIAxes2,1:length(app.diameter{app.index2}),app.diameter{app.index2},'b-');
                app.UIAxes2.YLim = ([0 round(max(app.diameter{app.index2}))+2]);
            end
        end

        % Value changed function: LengthofeachstepEditField
        function LengthofeachstepEditFieldValueChanged(app, event)
            if ~isempty(app.startline)
                app.diameter{app.index2}=[];
                delete(app.startline);
                delete(app.endline);
                
                length_steps = app.LengthofeachstepEditField.Value;
                steps = app.stepsEditField.Value;
                start_point = [app.hLine_balloon{app.index2}.Position(1,1) app.hLine_balloon{app.index2}.Position(1,2)];
                end_point = [app.hLine_balloon{app.index2}.Position(2,1) app.hLine_balloon{app.index2}.Position(2,2)];
                app.direction = end_point - start_point;
                pscan = linspace(0,1,steps+2);
                pscan = pscan(2:end-1);
                x = start_point(1) + pscan*app.direction(1);
                y = start_point(2) + pscan*app.direction(2);
                v = length_steps*app.direction/norm(app.direction);
                % plot the segments on figure
                for pseg = 1:length(pscan)
                    seg_start = [x(pseg)+v(2), x(pseg)-v(2)];
                    seg_end = [y(pseg)-v(1), y(pseg)+v(1)];
                    seg_int = improfile(app.image_DS,seg_start,seg_end);
                    app.xx = 1:0.01:length(seg_int);
                    app.c_interp = spline(1:length(seg_int),seg_int,app.xx);
                    [~,locs,~,~] = findpeaks(app.c_interp,app.xx,'SortStr',"descend",'MinPeakDistance',4,'WidthReference',"halfheight");
                    if length(locs) >= 2
                        app.diameter{app.index2}(pseg) = abs(locs(1)-locs(2));
                    elseif pseg >1
                        app.diameter{app.index2}(pseg) = app.diameter{app.index2}(pseg-1);
                    else
                        app.diameter{app.index2}(pseg) = 0;
                    end
                    if pseg == 1
                        app.startline=line(app.UIAxesDS,seg_start,seg_end,'LineWidth',1);
                    end
                    if pseg == length(pscan)
                        app.endline=line(app.UIAxesDS,seg_start,seg_end,'LineWidth',1);
                    end
                end
                % calculate the width for each line segments
                plot(app.UIAxes2,1:length(app.diameter{app.index2}),app.diameter{app.index2},'b-');
                app.UIAxes2.YLim = ([0 round(max(app.diameter{app.index2}))+2]);
            end
        end

        % Value changed function: DrawingmodeButton3
        function DrawingmodeButton3ValueChanged(app, event)
            value = app.DrawingmodeButton3.Value;
            if value == 1    
                app.VesiclediamemterumEditField.Value = '';
                app.index3 = app.index3 +1;
                app.hLine_myelin{app.index3} = drawline(app.UIAxesDS,'LineWidth',1,'Color','g',...
                    'Label',num2str(app.index3),'LabelAlpha',0.2,'LabelTextColor','g');             
                if pdist(app.hLine_myelin{app.index3}.Position,'euclidean') > 1 
                    hLine3 = drawline(app.UIAxes,'Color','g','Position',app.hLine_myelin{app.index3}.Position);
                    linkprop([app.hLine_myelin{app.index3} hLine3],'Position');                  
                    % add listeners for adjusting ROI & updating ROI line profile
                    app.l7{app.index3} = addlistener(app.hLine_myelin{app.index3},'ROIMoved',@(src,evt)roiallevents3(app,app.hLine_myelin{app.index3},evt));
                    app.l8{app.index3} = addlistener(app.hLine_myelin{app.index3},'DeletingROI',@(src,evt)roiallevents3(app,app.hLine_myelin{app.index3},evt));
                    app.l9{app.index3} = addlistener(app.hLine_myelin{app.index3},'ROIClicked',@(src,evt)roiallevents3(app,app.hLine_myelin{app.index3},evt));                   
                end                  
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.LoadqBRMdataButton = uibutton(app.UIFigure, 'push');
            app.LoadqBRMdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadqBRMdataButtonPushed, true);
            app.LoadqBRMdataButton.Text = 'Load qBRM data';
            app.FindmyelinabnormalitiesPanel = uipanel(app.UIFigure);
            app.FindmyelinabnormalitiesPanel.Title = 'Find myelin abnormalities';
            app.DrawingmodeButton3 = uibutton(app.FindmyelinabnormalitiesPanel, 'state');
            app.DrawingmodeButton3.ValueChangedFcn = createCallbackFcn(app, @DrawingmodeButton3ValueChanged, true);
            app.DrawingmodeButton3.Text = 'Drawing mode';
            app.myelinabnormalitiesEditFieldLabel = uilabel(app.FindmyelinabnormalitiesPanel);
            app.myelinabnormalitiesEditFieldLabel.Text = '# myelin abnormalities';
            app.myelinabnormalitiesEditField = uieditfield(app.FindmyelinabnormalitiesPanel, 'numeric');
            app.myelinabnormalitiesEditField.Editable = 'off';
            app.FindslopeofaxonalabnormalitiesPanel = uipanel(app.UIFigure);
            app.FindslopeofaxonalabnormalitiesPanel.Title = 'Find slope of axonal abnormalities';
            app.UIAxes2 = uiaxes(app.FindslopeofaxonalabnormalitiesPanel);
            title(app.UIAxes2, 'Line profile')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.MaximumdiameterumEditFieldLabel = uilabel(app.FindslopeofaxonalabnormalitiesPanel);
            app.MaximumdiameterumEditFieldLabel.Text = 'Maximum diameter (um)';
            app.MaximumdiameterumEditField = uieditfield(app.FindslopeofaxonalabnormalitiesPanel, 'text');
            app.MaximumdiameterumEditField.Editable = 'off';
            app.RatioEditFieldLabel = uilabel(app.FindslopeofaxonalabnormalitiesPanel);
            app.RatioEditFieldLabel.Text = 'Ratio';
            app.RatioEditField = uieditfield(app.FindslopeofaxonalabnormalitiesPanel, 'text');
            app.RatioEditField.Editable = 'off';
            app.stepsEditFieldLabel = uilabel(app.FindslopeofaxonalabnormalitiesPanel);
            app.stepsEditFieldLabel.Text = '# steps';
            app.stepsEditField = uieditfield(app.FindslopeofaxonalabnormalitiesPanel, 'numeric');
            app.stepsEditField.ValueChangedFcn = createCallbackFcn(app, @stepsEditFieldValueChanged, true);
            app.stepsEditField.Value = 25;
            app.LengthofeachstepEditFieldLabel = uilabel(app.FindslopeofaxonalabnormalitiesPanel);
            app.LengthofeachstepEditFieldLabel.Text = 'Length of each step';
            app.LengthofeachstepEditField = uieditfield(app.FindslopeofaxonalabnormalitiesPanel, 'numeric');
            app.LengthofeachstepEditField.ValueChangedFcn = createCallbackFcn(app, @LengthofeachstepEditFieldValueChanged, true);
            app.LengthofeachstepEditField.Value = 25;
            app.DrawingmodeButton2 = uibutton(app.FindslopeofaxonalabnormalitiesPanel, 'state');
            app.DrawingmodeButton2.ValueChangedFcn = createCallbackFcn(app, @DrawingmodeButton2ValueChanged, true);
            app.DrawingmodeButton2.Text = 'Drawing mode';
            app.FramesSliderLabel = uilabel(app.UIFigure);
            app.FramesSliderLabel.Text = 'Frames';
            app.FramesSlider = uislider(app.UIFigure);
            app.FramesSlider.Limits = [0 15];
            app.FramesSlider.ValueChangedFcn = createCallbackFcn(app, @FramesSliderValueChanged, true);
            app.FramesSlider.ValueChangingFcn = createCallbackFcn(app, @FramesSliderValueChanging, true);
            app.FramesSlider.MinorTicks = [];
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ZstackTab = uitab(app.TabGroup);
            app.ZstackTab.Title = 'Z stack';
            app.UIAxes = uiaxes(app.ZstackTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.FocusstackTab = uitab(app.TabGroup);
            app.FocusstackTab.Title = 'Focus stack';
            app.UIAxesDS = uiaxes(app.FocusstackTab);
            title(app.UIAxesDS, 'Title')
            xlabel(app.UIAxesDS, 'X')
            ylabel(app.UIAxesDS, 'Y')
            zlabel(app.UIAxesDS, 'Z')
            app.UIAxesDS.Visible = 'off';
            app.FileIOPanel = uipanel(app.UIFigure);
            app.FileIOPanel.Title = 'File I/O';
            app.FinishSaveButton = uibutton(app.FileIOPanel, 'push');
            app.FinishSaveButton.ButtonPushedFcn = createCallbackFcn(app, @FinishSaveButtonPushed, true);
            app.FinishSaveButton.Text = 'Finish & Save';
            app.FinddiameterofisolatedvesiclesPanel = uipanel(app.UIFigure);
            app.FinddiameterofisolatedvesiclesPanel.Title = 'Find diameter of isolated vesicles';
            app.UIAxes3 = uiaxes(app.FinddiameterofisolatedvesiclesPanel);
            title(app.UIAxes3, 'Line profile')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.VesiclediamemterumEditFieldLabel = uilabel(app.FinddiameterofisolatedvesiclesPanel);
            app.VesiclediamemterumEditFieldLabel.Text = 'Vesicle diamemter (um)';
            app.VesiclediamemterumEditField = uieditfield(app.FinddiameterofisolatedvesiclesPanel, 'text');
            app.VesiclediamemterumEditField.Editable = 'off';
            app.DrawingmodeButton = uibutton(app.FinddiameterofisolatedvesiclesPanel, 'state');
            app.DrawingmodeButton.ValueChangedFcn = createCallbackFcn(app, @DrawingmodeButtonValueChanged, true);
            app.DrawingmodeButton.Text = 'Drawing mode';
            app.MaxintensityLabel = uilabel(app.UIFigure);
            app.MaxintensityLabel.Text = 'Max intensity (%)';
            app.MaxintensitySpinner = uispinner(app.UIFigure);
            app.MaxintensitySpinner.ValueDisplayFormat = '%.0f';
            app.MaxintensitySpinner.ValueChangedFcn = createCallbackFcn(app, @MaxintensitySpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = qBRM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end