classdef sample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        LoadImageMenu             matlab.ui.container.Menu
        SelectsubjectfolderMenu   matlab.ui.container.Menu
        AD7688Menu                matlab.ui.container.Menu
        AD6692Menu                matlab.ui.container.Menu
        CTE7694Menu               matlab.ui.container.Menu
        CTE8921Menu               matlab.ui.container.Menu
        NCPTSD1Menu               matlab.ui.container.Menu
        NC7478Menu                matlab.ui.container.Menu
        LinkAxesMenu              matlab.ui.container.Menu
        LinkMenu                  matlab.ui.container.Menu
        SliceindexSpinner         matlab.ui.control.Spinner
        SliceindexSpinner_7Label  matlab.ui.control.Label
        SliceindexSpinner_6       matlab.ui.control.Spinner
        SliceindexSpinner_6Label  matlab.ui.control.Label
        SliceindexSpinner_5       matlab.ui.control.Spinner
        SliceindexSpinner_5Label  matlab.ui.control.Label
        SliceindexSpinner_4       matlab.ui.control.Spinner
        SliceindexSpinner_4Label  matlab.ui.control.Label
        SliceindexSpinner_3       matlab.ui.control.Spinner
        SliceindexSpinner_3Label  matlab.ui.control.Label
        SliceindexSpinner_2       matlab.ui.control.Spinner
        SliceindexSpinner_2Label  matlab.ui.control.Label
        NC7478Label               matlab.ui.control.Label
        NCPTSD1Label              matlab.ui.control.Label
        CTE8921Label              matlab.ui.control.Label
        CTE7694Label              matlab.ui.control.Label
        AD6692Label               matlab.ui.control.Label
        MaxSpinner_24             matlab.ui.control.Spinner
        MaxSpinner_24Label        matlab.ui.control.Label
        MinSpinner_24             matlab.ui.control.Spinner
        MinSpinner_24Label        matlab.ui.control.Label
        MaxSpinner_23             matlab.ui.control.Spinner
        MaxSpinner_23Label        matlab.ui.control.Label
        MinSpinner_23             matlab.ui.control.Spinner
        MinSpinner_23Label        matlab.ui.control.Label
        MaxSpinner_22             matlab.ui.control.Spinner
        MaxSpinner_22Label        matlab.ui.control.Label
        MinSpinner_22             matlab.ui.control.Spinner
        MinSpinner_22Label        matlab.ui.control.Label
        MaxSpinner_21             matlab.ui.control.Spinner
        MaxSpinner_21Label        matlab.ui.control.Label
        MinSpinner_21             matlab.ui.control.Spinner
        MinSpinner_21Label        matlab.ui.control.Label
        MaxSpinner_20             matlab.ui.control.Spinner
        MaxSpinner_20Label        matlab.ui.control.Label
        MinSpinner_20             matlab.ui.control.Spinner
        MinSpinner_20Label        matlab.ui.control.Label
        MaxSpinner_19             matlab.ui.control.Spinner
        MaxSpinner_19Label        matlab.ui.control.Label
        MinSpinner_19             matlab.ui.control.Spinner
        MinSpinner_19Label        matlab.ui.control.Label
        MaxSpinner_18             matlab.ui.control.Spinner
        MaxSpinner_18Label        matlab.ui.control.Label
        MinSpinner_18             matlab.ui.control.Spinner
        MinSpinner_18Label        matlab.ui.control.Label
        MaxSpinner_17             matlab.ui.control.Spinner
        MaxSpinner_17Label        matlab.ui.control.Label
        MinSpinner_17             matlab.ui.control.Spinner
        MinSpinner_17Label        matlab.ui.control.Label
        MaxSpinner_16             matlab.ui.control.Spinner
        MaxSpinner_16Label        matlab.ui.control.Label
        MinSpinner_16             matlab.ui.control.Spinner
        MinSpinner_16Label        matlab.ui.control.Label
        MaxSpinner_15             matlab.ui.control.Spinner
        MaxSpinner_15Label        matlab.ui.control.Label
        MinSpinner_15             matlab.ui.control.Spinner
        MinSpinner_15Label        matlab.ui.control.Label
        MaxSpinner_14             matlab.ui.control.Spinner
        MaxSpinner_14Label        matlab.ui.control.Label
        MinSpinner_14             matlab.ui.control.Spinner
        MinSpinner_14Label        matlab.ui.control.Label
        MaxSpinner_13             matlab.ui.control.Spinner
        MaxSpinner_13Label        matlab.ui.control.Label
        MinSpinner_13             matlab.ui.control.Spinner
        MinSpinner_13Label        matlab.ui.control.Label
        MaxSpinner_12             matlab.ui.control.Spinner
        MaxSpinner_12Label        matlab.ui.control.Label
        MinSpinner_12             matlab.ui.control.Spinner
        MinSpinner_12Label        matlab.ui.control.Label
        MaxSpinner_11             matlab.ui.control.Spinner
        MaxSpinner_11Label        matlab.ui.control.Label
        MinSpinner_11             matlab.ui.control.Spinner
        MinSpinner_11Label        matlab.ui.control.Label
        MaxSpinner_10             matlab.ui.control.Spinner
        MaxSpinner_10Label        matlab.ui.control.Label
        MinSpinner_10             matlab.ui.control.Spinner
        MinSpinner_10Label        matlab.ui.control.Label
        MaxSpinner_9              matlab.ui.control.Spinner
        MaxSpinner_9Label         matlab.ui.control.Label
        MinSpinner_9              matlab.ui.control.Spinner
        MinSpinner_9Label         matlab.ui.control.Label
        MaxSpinner_8              matlab.ui.control.Spinner
        MaxSpinner_8Label         matlab.ui.control.Label
        MaxSpinner_3              matlab.ui.control.Spinner
        MaxSpinner_3Label         matlab.ui.control.Label
        MinSpinner_3              matlab.ui.control.Spinner
        MinSpinner_3Label         matlab.ui.control.Label
        MinSpinner_8              matlab.ui.control.Spinner
        MinSpinner_8Label         matlab.ui.control.Label
        MaxSpinner_7              matlab.ui.control.Spinner
        MaxSpinner_7Label         matlab.ui.control.Label
        MinSpinner_7              matlab.ui.control.Spinner
        MinSpinner_7Label         matlab.ui.control.Label
        MaxSpinner_6              matlab.ui.control.Spinner
        MaxSpinner_6Label         matlab.ui.control.Label
        MinSpinner_6              matlab.ui.control.Spinner
        MinSpinner_6Label         matlab.ui.control.Label
        MaxSpinner_5              matlab.ui.control.Spinner
        MaxSpinner_5Label         matlab.ui.control.Label
        MinSpinner_5              matlab.ui.control.Spinner
        MinSpinner_5Label         matlab.ui.control.Label
        AD7688Label               matlab.ui.control.Label
        MaxSpinner_4              matlab.ui.control.Spinner
        MaxSpinner_4Label         matlab.ui.control.Label
        MinSpinner_4              matlab.ui.control.Spinner
        MinSpinner_4Label         matlab.ui.control.Label
        MaxSpinner_2              matlab.ui.control.Spinner
        MaxSpinner_2Label         matlab.ui.control.Label
        MinSpinner_2              matlab.ui.control.Spinner
        MinSpinner_2Label         matlab.ui.control.Label
        retLabel                  matlab.ui.control.Label
        mubmusLabel               matlab.ui.control.Label
        MIPLabel                  matlab.ui.control.Label
        musLabel                  matlab.ui.control.Label
        MinSpinner                matlab.ui.control.Spinner
        MinSpinnerLabel           matlab.ui.control.Label
        MaxSpinner                matlab.ui.control.Spinner
        MaxSpinnerLabel           matlab.ui.control.Label
        UIAxes_3                  matlab.ui.control.UIAxes
        UIAxes_24                 matlab.ui.control.UIAxes
        UIAxes_23                 matlab.ui.control.UIAxes
        UIAxes_22                 matlab.ui.control.UIAxes
        UIAxes_21                 matlab.ui.control.UIAxes
        UIAxes_20                 matlab.ui.control.UIAxes
        UIAxes_19                 matlab.ui.control.UIAxes
        UIAxes_18                 matlab.ui.control.UIAxes
        UIAxes_17                 matlab.ui.control.UIAxes
        UIAxes_16                 matlab.ui.control.UIAxes
        UIAxes_15                 matlab.ui.control.UIAxes
        UIAxes_14                 matlab.ui.control.UIAxes
        UIAxes_13                 matlab.ui.control.UIAxes
        UIAxes_12                 matlab.ui.control.UIAxes
        UIAxes_11                 matlab.ui.control.UIAxes
        UIAxes_10                 matlab.ui.control.UIAxes
        UIAxes_9                  matlab.ui.control.UIAxes
        UIAxes_8                  matlab.ui.control.UIAxes
        UIAxes_7                  matlab.ui.control.UIAxes
        UIAxes_6                  matlab.ui.control.UIAxes
        UIAxes_5                  matlab.ui.control.UIAxes
        UIAxes_4                  matlab.ui.control.UIAxes
        UIAxes_2                  matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image % the image data
        image2
        image3
        image4
        image5
        image6
        image7
        image8
        image9
        image10
        image11
        image12
        image13
        image14
        image15
        image16
        image17
        image18
        image19
        image20
        image21
        image22
        image23
        image24
        slice_ind % the index of the current slice
        slice_ind2
        slice_ind3
        slice_ind4
        slice_ind5
        slice_ind6
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: MinSpinner
        function MinSpinnerValueChanged(app, event)
            value = app.MinSpinner.Value;
            if value < app.MaxSpinner.Value
               if length(size(app.image))>2
                   app.UIAxes.CLim = [value app.MaxSpinner.Value];
                   imagesc(app.UIAxes, squeeze(app.image(app.slice_ind,:,:)));
               else
                   app.UIAxes.CLim = [value app.MaxSpinner.Value];
                   imagesc(app.UIAxes, app.image);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end
        end

        % Value changed function: SliceindexSpinner
        function SliceindexSpinnerValueChanged(app, event)
            value = app.SliceindexSpinner.Value;
            app.slice_ind = value;
            if length(size(app.image))>2
                imagesc(app.UIAxes, squeeze(app.image(app.slice_ind,:,:)));
                imagesc(app.UIAxes_2, squeeze(app.image2(app.slice_ind,:,:)));
                imagesc(app.UIAxes_3, squeeze(app.image3(app.slice_ind,:,:)));
                imagesc(app.UIAxes_4, squeeze(app.image4(app.slice_ind,:,:)));
            else
                imagesc(app.UIAxes, app.image);
                imagesc(app.UIAxes_2, app.image2);
                imagesc(app.UIAxes_3, app.image3);
                imagesc(app.UIAxes_4, app.image4);
            end
        end

        % Value changed function: MaxSpinner
        function MaxSpinnerValueChanged(app, event)
            value = app.MaxSpinner.Value;
            if value > app.MinSpinner.Value
               if length(size(app.image))>2
                   app.UIAxes.CLim = [app.MinSpinner.Value value];
                   imagesc(app.UIAxes, squeeze(app.image(app.slice_ind,:,:)));
               else
                   app.UIAxes.CLim = [app.MinSpinner.Value value];
                   imagesc(app.UIAxes, app.image);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end
        end

        % Value changed function: MaxSpinner_5
        function MaxSpinner_5ValueChanged(app, event)
            value = app.MaxSpinner_5.Value;
            if value > app.MinSpinner_5.Value
               if length(size(app.image5))>2
                   app.UIAxes_5.CLim = [app.MinSpinner_5.Value value];
                   imagesc(app.UIAxes_5, squeeze(app.image5(app.slice_ind2,:,:)));
               else
                   app.UIAxes_5.CLim = [app.MinSpinner_5.Value value];
                   imagesc(app.UIAxes_5, app.image5);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end
        end

        % Value changed function: MinSpinner_5
        function MinSpinner_5ValueChanged(app, event)
            value = app.MinSpinner_5.Value;
            if value < app.MaxSpinner_5.Value
               if length(size(app.image5))>2
                   app.UIAxes_5.CLim = [value app.MaxSpinner_5.Value];
                   imagesc(app.UIAxes_5, squeeze(app.image5(app.slice_ind2,:,:)));
               else
                   app.UIAxes_5.CLim = [value app.MaxSpinner_5.Value];
                   imagesc(app.UIAxes_5, app.image5);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end
        end

        % Value changed function: SliceindexSpinner_2
        function SliceindexSpinner_2ValueChanged(app, event)
            value = app.SliceindexSpinner_2.Value;
            app.slice_ind2 = value;
            if length(size(app.image5))>2
                imagesc(app.UIAxes_5, squeeze(app.image5(app.slice_ind2,:,:)));
                imagesc(app.UIAxes_6, squeeze(app.image6(app.slice_ind2,:,:)));
                imagesc(app.UIAxes_7, squeeze(app.image7(app.slice_ind2,:,:)));
                imagesc(app.UIAxes_8, squeeze(app.image8(app.slice_ind2,:,:)));
            else
                imagesc(app.UIAxes_5, app.image5);
                imagesc(app.UIAxes_6, app.image6);
                imagesc(app.UIAxes_7, app.image7);
                imagesc(app.UIAxes_8, app.image8);
            end
        end

        % Value changed function: MinSpinner_2
        function MinSpinner_2ValueChanged(app, event)
            value = app.MinSpinner_2.Value;
            if value < app.MaxSpinner_2.Value
               if length(size(app.image2))>2
                   app.UIAxes_2.CLim = [value app.MaxSpinner_2.Value];
                   imagesc(app.UIAxes_2, squeeze(app.image2(app.slice_ind,:,:)));
               else
                   app.UIAxes_2.CLim = [value app.MaxSpinner_2.Value];
                   imagesc(app.UIAxes_2, app.image2);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end            
        end

        % Value changed function: MinSpinner_3
        function MinSpinner_3ValueChanged(app, event)
            value = app.MinSpinner_3.Value;
            if value < app.MaxSpinner_3.Value
               if length(size(app.image3))>2
                   app.UIAxes_3.CLim = [value app.MaxSpinner_3.Value];
                   imagesc(app.UIAxes_3, squeeze(app.image3(app.slice_ind,:,:)));
               else
                   app.UIAxes_3.CLim = [value app.MaxSpinner_3.Value];
                   imagesc(app.UIAxes_3, app.image3);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end               
        end

        % Value changed function: MinSpinner_4
        function MinSpinner_4ValueChanged(app, event)
            value = app.MinSpinner_4.Value;
            if value < app.MaxSpinner_4.Value
               if length(size(app.image4))>2
                   app.UIAxes_4.CLim = [value app.MaxSpinner_4.Value];
                   imagesc(app.UIAxes_4, squeeze(app.image4(app.slice_ind,:,:)));
               else
                   app.UIAxes_4.CLim = [value app.MaxSpinner_4.Value];
                   imagesc(app.UIAxes_4, app.image2);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end               
        end

        % Value changed function: MaxSpinner_2
        function MaxSpinner_2ValueChanged(app, event)
            value = app.MaxSpinner_2.Value;
            if value > app.MinSpinner_2.Value
               if length(size(app.image2))>2
                   app.UIAxes_2.CLim = [app.MinSpinner_2.Value value];
                   imagesc(app.UIAxes_2, squeeze(app.image2(app.slice_ind,:,:)));
               else
                   app.UIAxes_2.CLim = [app.MinSpinner_2.Value value];
                   imagesc(app.UIAxes_2, app.image2);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end            
        end

        % Value changed function: MaxSpinner_3
        function MaxSpinner_3ValueChanged(app, event)
            value = app.MaxSpinner_3.Value;
            if value > app.MinSpinner_3.Value
               if length(size(app.image3))>2
                   app.UIAxes_3.CLim = [app.MinSpinner_3.Value value];
                   imagesc(app.UIAxes_3, squeeze(app.image3(app.slice_ind,:,:)));
               else
                   app.UIAxes_3.CLim = [app.MinSpinner_3.Value value];
                   imagesc(app.UIAxes_3, app.image3);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end             
        end

        % Value changed function: MaxSpinner_4
        function MaxSpinner_4ValueChanged(app, event)
            value = app.MaxSpinner_4.Value;
            if value > app.MinSpinner_4.Value
               if length(size(app.image4))>2
                   app.UIAxes_4.CLim = [app.MinSpinner_4.Value value];
                   imagesc(app.UIAxes_4, squeeze(app.image4(app.slice_ind,:,:)));
               else
                   app.UIAxes_4.CLim = [app.MinSpinner_4.Value value];
                   imagesc(app.UIAxes_4, app.image4);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end             
        end

        % Value changed function: MinSpinner_6
        function MinSpinner_6ValueChanged(app, event)
            value = app.MinSpinner_6.Value;
            if value < app.MaxSpinner_6.Value
               if length(size(app.image6))>2
                   app.UIAxes_6.CLim = [value app.MaxSpinner_6.Value];
                   imagesc(app.UIAxes_6, squeeze(app.image6(app.slice_ind2,:,:)));
               else
                   app.UIAxes_6.CLim = [value app.MaxSpinner_6.Value];
                   imagesc(app.UIAxes_6, app.image6);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end            
        end

        % Value changed function: MinSpinner_7
        function MinSpinner_7ValueChanged(app, event)
            value = app.MinSpinner_7.Value;
            if value < app.MaxSpinner_7.Value
               if length(size(app.image7))>2
                   app.UIAxes_7.CLim = [value app.MaxSpinner_7.Value];
                   imagesc(app.UIAxes_7, squeeze(app.image7(app.slice_ind2,:,:)));
               else
                   app.UIAxes_7.CLim = [value app.MaxSpinner_7.Value];
                   imagesc(app.UIAxes_7, app.image7);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end
        end

        % Value changed function: MinSpinner_8
        function MinSpinner_8ValueChanged(app, event)
            value = app.MinSpinner_8.Value;
            if value < app.MaxSpinner_8.Value
               if length(size(app.image8))>2
                   app.UIAxes_8.CLim = [value app.MaxSpinner_8.Value];
                   imagesc(app.UIAxes_8, squeeze(app.image8(app.slice_ind2,:,:)));
               else
                   app.UIAxes_8.CLim = [value app.MaxSpinner_8.Value];
                   imagesc(app.UIAxes_8, app.image8);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end            
        end

        % Value changed function: MaxSpinner_6
        function MaxSpinner_6ValueChanged(app, event)
            value = app.MaxSpinner_6.Value;
            if value > app.MinSpinner_6.Value
               if length(size(app.image6))>2
                   app.UIAxes_6.CLim = [app.MinSpinner_6.Value value];
                   imagesc(app.UIAxes_6, squeeze(app.image6(app.slice_ind2,:,:)));
               else
                   app.UIAxes_6.CLim = [app.MinSpinner_6.Value value];
                   imagesc(app.UIAxes_6, app.image6);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end            
        end

        % Value changed function: MaxSpinner_7
        function MaxSpinner_7ValueChanged(app, event)
            value = app.MaxSpinner_7.Value;
            if value > app.MinSpinner_7.Value
               if length(size(app.image7))>2
                   app.UIAxes_7.CLim = [app.MinSpinner_7.Value value];
                   imagesc(app.UIAxes_7, squeeze(app.image7(app.slice_ind2,:,:)));
               else
                   app.UIAxes_7.CLim = [app.MinSpinner_7.Value value];
                   imagesc(app.UIAxes_7, app.image7);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end            
        end

        % Value changed function: MaxSpinner_8
        function MaxSpinner_8ValueChanged(app, event)
            value = app.MaxSpinner_8.Value;
            if value > app.MinSpinner_8.Value
               if length(size(app.image8))>2
                   app.UIAxes_8.CLim = [app.MinSpinner_8.Value value];
                   imagesc(app.UIAxes_8, squeeze(app.image8(app.slice_ind2,:,:)));
               else
                   app.UIAxes_8.CLim = [app.MinSpinner_8.Value value];
                   imagesc(app.UIAxes_8, app.image8);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end            
        end

        % Value changed function: SliceindexSpinner_3
        function SliceindexSpinner_3ValueChanged(app, event)
            value = app.SliceindexSpinner_3.Value;
            app.slice_ind3 = value;
            if length(size(app.image9))>2
                imagesc(app.UIAxes_9, squeeze(app.image9(app.slice_ind3,:,:)));
                imagesc(app.UIAxes_10, squeeze(app.image10(app.slice_ind3,:,:)));
                imagesc(app.UIAxes_11, squeeze(app.image11(app.slice_ind3,:,:)));
                imagesc(app.UIAxes_12, squeeze(app.image12(app.slice_ind3,:,:)));
            else
                imagesc(app.UIAxes_9, app.image9);
                imagesc(app.UIAxes_10, app.image10);
                imagesc(app.UIAxes_11, app.image11);
                imagesc(app.UIAxes_12, app.image12);
            end            
        end

        % Value changed function: SliceindexSpinner_4
        function SliceindexSpinner_4ValueChanged(app, event)
            value = app.SliceindexSpinner_4.Value;
            app.slice_ind4 = value;
            if length(size(app.image13))>2
                imagesc(app.UIAxes_13, squeeze(app.image13(app.slice_ind4,:,:)));
                imagesc(app.UIAxes_14, squeeze(app.image14(app.slice_ind4,:,:)));
                imagesc(app.UIAxes_15, squeeze(app.image15(app.slice_ind4,:,:)));
                imagesc(app.UIAxes_16, squeeze(app.image16(app.slice_ind4,:,:)));
            else
                imagesc(app.UIAxes_13, app.image13);
                imagesc(app.UIAxes_14, app.image14);
                imagesc(app.UIAxes_15, app.image15);
                imagesc(app.UIAxes_16, app.image16);
            end            
        end

        % Value changed function: SliceindexSpinner_5
        function SliceindexSpinner_5ValueChanged(app, event)
            value = app.SliceindexSpinner_5.Value;
            app.slice_ind5 = value;
            if length(size(app.image17))>2
                imagesc(app.UIAxes_17, squeeze(app.image17(app.slice_ind5,:,:)));
                imagesc(app.UIAxes_18, squeeze(app.image18(app.slice_ind5,:,:)));
                imagesc(app.UIAxes_19, squeeze(app.image19(app.slice_ind5,:,:)));
                imagesc(app.UIAxes_20, squeeze(app.image20(app.slice_ind5,:,:)));
            else
                imagesc(app.UIAxes_17, app.image17);
                imagesc(app.UIAxes_18, app.image18);
                imagesc(app.UIAxes_19, app.image19);
                imagesc(app.UIAxes_20, app.image20);
            end            
        end

        % Value changed function: SliceindexSpinner_6
        function SliceindexSpinner_6ValueChanged(app, event)
            value = app.SliceindexSpinner_6.Value;
            app.slice_ind6 = value;
            if length(size(app.image21))>2
                imagesc(app.UIAxes_21, squeeze(app.image21(app.slice_ind6,:,:)));
                imagesc(app.UIAxes_22, squeeze(app.image22(app.slice_ind6,:,:)));
                imagesc(app.UIAxes_23, squeeze(app.image23(app.slice_ind6,:,:)));
                imagesc(app.UIAxes_24, squeeze(app.image24(app.slice_ind6,:,:)));
            else
                imagesc(app.UIAxes_21, app.image21);
                imagesc(app.UIAxes_22, app.image22);
                imagesc(app.UIAxes_23, app.image23);
                imagesc(app.UIAxes_24, app.image24);
            end            
        end

        % Value changed function: MaxSpinner_9
        function MaxSpinner_9ValueChanged(app, event)
            value = app.MaxSpinner_9.Value;
            if value > app.MinSpinner_9.Value
               if length(size(app.image9))>2
                   app.UIAxes_9.CLim = [app.MinSpinner_9.Value value];
                   imagesc(app.UIAxes_9, squeeze(app.image9(app.slice_ind3,:,:)));
               else
                   app.UIAxes_9.CLim = [app.MinSpinner_9.Value value];
                   imagesc(app.UIAxes_9, app.image9);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end              
        end

        % Value changed function: MaxSpinner_10
        function MaxSpinner_10ValueChanged(app, event)
            value = app.MaxSpinner_10.Value;
            if value > app.MinSpinner_10.Value
               if length(size(app.image10))>2
                   app.UIAxes_10.CLim = [app.MinSpinner_10.Value value];
                   imagesc(app.UIAxes_10, squeeze(app.image10(app.slice_ind3,:,:)));
               else
                   app.UIAxes_10.CLim = [app.MinSpinner_10.Value value];
                   imagesc(app.UIAxes_10, app.image10);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end             
        end

        % Value changed function: MaxSpinner_11
        function MaxSpinner_11ValueChanged(app, event)
            value = app.MaxSpinner_11.Value;
            if value > app.MinSpinner_11.Value
               if length(size(app.image11))>2
                   app.UIAxes_11.CLim = [app.MinSpinner_11.Value value];
                   imagesc(app.UIAxes_11, squeeze(app.image11(app.slice_ind3,:,:)));
               else
                   app.UIAxes_11.CLim = [app.MinSpinner_11.Value value];
                   imagesc(app.UIAxes_11, app.image11);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end             
        end

        % Value changed function: MaxSpinner_12
        function MaxSpinner_12ValueChanged(app, event)
            value = app.MaxSpinner_12.Value;
            if value > app.MinSpinner_12.Value
               if length(size(app.image12))>2
                   app.UIAxes_12.CLim = [app.MinSpinner_12.Value value];
                   imagesc(app.UIAxes_12, squeeze(app.image12(app.slice_ind3,:,:)));
               else
                   app.UIAxes_12.CLim = [app.MinSpinner_12.Value value];
                   imagesc(app.UIAxes_12, app.image12);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end             
        end

        % Value changed function: MaxSpinner_13
        function MaxSpinner_13ValueChanged(app, event)
            value = app.MaxSpinner_13.Value;
            if value > app.MinSpinner_13.Value
               if length(size(app.image13))>2
                   app.UIAxes_13.CLim = [app.MinSpinner_13.Value value];
                   imagesc(app.UIAxes_13, squeeze(app.image13(app.slice_ind4,:,:)));
               else
                   app.UIAxes_13.CLim = [app.MinSpinner_13.Value value];
                   imagesc(app.UIAxes_13, app.image13);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end             
        end

        % Value changed function: MaxSpinner_14
        function MaxSpinner_14ValueChanged(app, event)
            value = app.MaxSpinner_14.Value;
            if value > app.MinSpinner_14.Value
               if length(size(app.image14))>2
                   app.UIAxes_14.CLim = [app.MinSpinner_14.Value value];
                   imagesc(app.UIAxes_14, squeeze(app.image14(app.slice_ind4,:,:)));
               else
                   app.UIAxes_14.CLim = [app.MinSpinner_14.Value value];
                   imagesc(app.UIAxes_14, app.image14);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end              
        end

        % Value changed function: MaxSpinner_15
        function MaxSpinner_15ValueChanged(app, event)
            value = app.MaxSpinner_15.Value;
            if value > app.MinSpinner_15.Value
               if length(size(app.image15))>2
                   app.UIAxes_15.CLim = [app.MinSpinner_15.Value value];
                   imagesc(app.UIAxes_15, squeeze(app.image15(app.slice_ind4,:,:)));
               else
                   app.UIAxes_15.CLim = [app.MinSpinner_15.Value value];
                   imagesc(app.UIAxes_15, app.image15);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end              
        end

        % Value changed function: MaxSpinner_16
        function MaxSpinner_16ValueChanged(app, event)
            value = app.MaxSpinner_16.Value;
            if value > app.MinSpinner_16.Value
               if length(size(app.image16))>2
                   app.UIAxes_16.CLim = [app.MinSpinner_16.Value value];
                   imagesc(app.UIAxes_16, squeeze(app.image16(app.slice_ind4,:,:)));
               else
                   app.UIAxes_16.CLim = [app.MinSpinner_16.Value value];
                   imagesc(app.UIAxes_16, app.image16);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end              
        end

        % Value changed function: MaxSpinner_17
        function MaxSpinner_17ValueChanged(app, event)
            value = app.MaxSpinner_17.Value;
            if value > app.MinSpinner_17.Value
               if length(size(app.image17))>2
                   app.UIAxes_17.CLim = [app.MinSpinner_17.Value value];
                   imagesc(app.UIAxes_17, squeeze(app.image17(app.slice_ind5,:,:)));
               else
                   app.UIAxes_17.CLim = [app.MinSpinner_17.Value value];
                   imagesc(app.UIAxes_17, app.image17);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end              
        end

        % Value changed function: MaxSpinner_18
        function MaxSpinner_18ValueChanged(app, event)
            value = app.MaxSpinner_18.Value;
            if value > app.MinSpinner_18.Value
               if length(size(app.image18))>2
                   app.UIAxes_18.CLim = [app.MinSpinner_18.Value value];
                   imagesc(app.UIAxes_18, squeeze(app.image18(app.slice_ind5,:,:)));
               else
                   app.UIAxes_18.CLim = [app.MinSpinner_18.Value value];
                   imagesc(app.UIAxes_18, app.image18);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end              
        end

        % Value changed function: MaxSpinner_19
        function MaxSpinner_19ValueChanged(app, event)
            value = app.MaxSpinner_19.Value;
            if value > app.MinSpinner_19.Value
               if length(size(app.image19))>2
                   app.UIAxes_19.CLim = [app.MinSpinner_19.Value value];
                   imagesc(app.UIAxes_19, squeeze(app.image19(app.slice_ind5,:,:)));
               else
                   app.UIAxes_19.CLim = [app.MinSpinner_19.Value value];
                   imagesc(app.UIAxes_19, app.image19);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end              
        end

        % Value changed function: MaxSpinner_20
        function MaxSpinner_20ValueChanged(app, event)
            value = app.MaxSpinner_20.Value;
            if value > app.MinSpinner_20.Value
               if length(size(app.image20))>2
                   app.UIAxes_20.CLim = [app.MinSpinner_20.Value value];
                   imagesc(app.UIAxes_20, squeeze(app.image20(app.slice_ind5,:,:)));
               else
                   app.UIAxes_20.CLim = [app.MinSpinner_20.Value value];
                   imagesc(app.UIAxes_20, app.image20);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end              
        end

        % Value changed function: MaxSpinner_21
        function MaxSpinner_21ValueChanged(app, event)
            value = app.MaxSpinner_21.Value;
            if value > app.MinSpinner_21.Value
               if length(size(app.image21))>2
                   app.UIAxes_21.CLim = [app.MinSpinner_21.Value value];
                   imagesc(app.UIAxes_21, squeeze(app.image21(app.slice_ind6,:,:)));
               else
                   app.UIAxes_21.CLim = [app.MinSpinner_21.Value value];
                   imagesc(app.UIAxes_21, app.image21);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end              
        end

        % Value changed function: MaxSpinner_22
        function MaxSpinner_22ValueChanged(app, event)
            value = app.MaxSpinner_22.Value;
            if value > app.MinSpinner_22.Value
               if length(size(app.image22))>2
                   app.UIAxes_22.CLim = [app.MinSpinner_22.Value value];
                   imagesc(app.UIAxes_22, squeeze(app.image22(app.slice_ind6,:,:)));
               else
                   app.UIAxes_22.CLim = [app.MinSpinner_22.Value value];
                   imagesc(app.UIAxes_22, app.image22);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end                   
        end

        % Value changed function: MaxSpinner_23
        function MaxSpinner_23ValueChanged(app, event)
            value = app.MaxSpinner_23.Value;
            if value > app.MinSpinner_23.Value
               if length(size(app.image23))>2
                   app.UIAxes_23.CLim = [app.MinSpinner_23.Value value];
                   imagesc(app.UIAxes_23, squeeze(app.image23(app.slice_ind6,:,:)));
               else
                   app.UIAxes_23.CLim = [app.MinSpinner_23.Value value];
                   imagesc(app.UIAxes_23, app.image23);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end                   
        end

        % Value changed function: MaxSpinner_24
        function MaxSpinner_24ValueChanged(app, event)
            value = app.MaxSpinner_24.Value;
            if value > app.MinSpinner_24.Value
               if length(size(app.image24))>2
                   app.UIAxes_24.CLim = [app.MinSpinner_24.Value value];
                   imagesc(app.UIAxes_24, squeeze(app.image24(app.slice_ind6,:,:)));
               else
                   app.UIAxes_24.CLim = [app.MinSpinner_24.Value value];
                   imagesc(app.UIAxes_24, app.image24);
               end
            else
                msgbox('Maximal intensity smaller than min!');
            end                   
        end

        % Value changed function: MinSpinner_9
        function MinSpinner_9ValueChanged(app, event)
            value = app.MinSpinner_9.Value;
            if value < app.MaxSpinner_9.Value
               if length(size(app.image9))>2
                   app.UIAxes_9.CLim = [value app.MaxSpinner_9.Value];
                   imagesc(app.UIAxes_9, squeeze(app.image9(app.slice_ind3,:,:)));
               else
                   app.UIAxes_9.CLim = [value app.MaxSpinner_9.Value];
                   imagesc(app.UIAxes_9, app.image9);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end             
        end

        % Value changed function: MinSpinner_10
        function MinSpinner_10ValueChanged(app, event)
            value = app.MinSpinner_10.Value;
            if value < app.MaxSpinner_10.Value
               if length(size(app.image10))>2
                   app.UIAxes_10.CLim = [value app.MaxSpinner_10.Value];
                   imagesc(app.UIAxes_10, squeeze(app.image10(app.slice_ind3,:,:)));
               else
                   app.UIAxes_10.CLim = [value app.MaxSpinner_10.Value];
                   imagesc(app.UIAxes_10, app.image10);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end              
        end

        % Value changed function: MinSpinner_11
        function MinSpinner_11ValueChanged(app, event)
            value = app.MinSpinner_11.Value;
            if value < app.MaxSpinner_11.Value
               if length(size(app.image11))>2
                   app.UIAxes_11.CLim = [value app.MaxSpinner_11.Value];
                   imagesc(app.UIAxes_11, squeeze(app.image11(app.slice_ind3,:,:)));
               else
                   app.UIAxes_11.CLim = [value app.MaxSpinner_11.Value];
                   imagesc(app.UIAxes_11, app.image11);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end              
        end

        % Value changed function: MinSpinner_12
        function MinSpinner_12ValueChanged(app, event)
            value = app.MinSpinner_12.Value;
            if value < app.MaxSpinner_12.Value
               if length(size(app.image12))>2
                   app.UIAxes_12.CLim = [value app.MaxSpinner_12.Value];
                   imagesc(app.UIAxes_12, squeeze(app.image12(app.slice_ind3,:,:)));
               else
                   app.UIAxes_12.CLim = [value app.MaxSpinner_12.Value];
                   imagesc(app.UIAxes_12, app.image12);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end              
        end

        % Value changed function: MinSpinner_13
        function MinSpinner_13ValueChanged(app, event)
            value = app.MinSpinner_13.Value;
            if value < app.MaxSpinner_13.Value
               if length(size(app.image13))>2
                   app.UIAxes_13.CLim = [value app.MaxSpinner_13.Value];
                   imagesc(app.UIAxes_13, squeeze(app.image13(app.slice_ind4,:,:)));
               else
                   app.UIAxes_13.CLim = [value app.MaxSpinner_13.Value];
                   imagesc(app.UIAxes_13, app.image13);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end                
        end

        % Value changed function: MinSpinner_14
        function MinSpinner_14ValueChanged(app, event)
            value = app.MinSpinner_14.Value;
            if value < app.MaxSpinner_14.Value
               if length(size(app.image14))>2
                   app.UIAxes_14.CLim = [value app.MaxSpinner_14.Value];
                   imagesc(app.UIAxes_14, squeeze(app.image14(app.slice_ind4,:,:)));
               else
                   app.UIAxes_14.CLim = [value app.MaxSpinner_14.Value];
                   imagesc(app.UIAxes_14, app.image14);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end              
        end

        % Value changed function: MinSpinner_15
        function MinSpinner_15ValueChanged(app, event)
            value = app.MinSpinner_15.Value;
            if value < app.MaxSpinner_15.Value
               if length(size(app.image15))>2
                   app.UIAxes_15.CLim = [value app.MaxSpinner_15.Value];
                   imagesc(app.UIAxes_15, squeeze(app.image15(app.slice_ind4,:,:)));
               else
                   app.UIAxes_15.CLim = [value app.MaxSpinner_15.Value];
                   imagesc(app.UIAxes_15, app.image15);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end              
        end

        % Value changed function: MinSpinner_16
        function MinSpinner_16ValueChanged(app, event)
            value = app.MinSpinner_16.Value;
            if value < app.MaxSpinner_16.Value
               if length(size(app.image16))>2
                   app.UIAxes_16.CLim = [value app.MaxSpinner_16.Value];
                   imagesc(app.UIAxes_16, squeeze(app.image16(app.slice_ind4,:,:)));
               else
                   app.UIAxes_16.CLim = [value app.MaxSpinner_16.Value];
                   imagesc(app.UIAxes_16, app.image16);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end              
        end

        % Value changed function: MinSpinner_17
        function MinSpinner_17ValueChanged(app, event)
            value = app.MinSpinner_17.Value;
            if value < app.MaxSpinner_17.Value
               if length(size(app.image17))>2
                   app.UIAxes_17.CLim = [value app.MaxSpinner_17.Value];
                   imagesc(app.UIAxes_17, squeeze(app.image17(app.slice_ind5,:,:)));
               else
                   app.UIAxes_17.CLim = [value app.MaxSpinner_17.Value];
                   imagesc(app.UIAxes_17, app.image17);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end              
        end

        % Value changed function: MinSpinner_18
        function MinSpinner_18ValueChanged(app, event)
            value = app.MinSpinner_18.Value;
            if value < app.MaxSpinner_18.Value
               if length(size(app.image18))>2
                   app.UIAxes_18.CLim = [value app.MaxSpinner_18.Value];
                   imagesc(app.UIAxes_18, squeeze(app.image18(app.slice_ind5,:,:)));
               else
                   app.UIAxes_18.CLim = [value app.MaxSpinner_18.Value];
                   imagesc(app.UIAxes_18, app.image18);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end              
        end

        % Value changed function: MinSpinner_19
        function MinSpinner_19ValueChanged(app, event)
            value = app.MinSpinner_19.Value;
            if value < app.MaxSpinner_19.Value
               if length(size(app.image19))>2
                   app.UIAxes_19.CLim = [value app.MaxSpinner_19.Value];
                   imagesc(app.UIAxes_19, squeeze(app.image19(app.slice_ind5,:,:)));
               else
                   app.UIAxes_19.CLim = [value app.MaxSpinner_19.Value];
                   imagesc(app.UIAxes_19, app.image19);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end              
        end

        % Value changed function: MinSpinner_20
        function MinSpinner_20ValueChanged(app, event)
            value = app.MinSpinner_20.Value;
            if value < app.MaxSpinner_20.Value
               if length(size(app.image20))>2
                   app.UIAxes_20.CLim = [value app.MaxSpinner_20.Value];
                   imagesc(app.UIAxes_20, squeeze(app.image20(app.slice_ind5,:,:)));
               else
                   app.UIAxes_20.CLim = [value app.MaxSpinner_20.Value];
                   imagesc(app.UIAxes_20, app.image20);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end              
        end

        % Value changed function: MinSpinner_21
        function MinSpinner_21ValueChanged(app, event)
            value = app.MinSpinner_21.Value;
            if value < app.MaxSpinner_21.Value
               if length(size(app.image21))>2
                   app.UIAxes_21.CLim = [value app.MaxSpinner_21.Value];
                   imagesc(app.UIAxes_21, squeeze(app.image21(app.slice_ind6,:,:)));
               else
                   app.UIAxes_21.CLim = [value app.MaxSpinner_21.Value];
                   imagesc(app.UIAxes_21, app.image21);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end              
        end

        % Value changed function: MinSpinner_22
        function MinSpinner_22ValueChanged(app, event)
            value = app.MinSpinner_22.Value;
            if value < app.MaxSpinner_22.Value
               if length(size(app.image22))>2
                   app.UIAxes_22.CLim = [value app.MaxSpinner_22.Value];
                   imagesc(app.UIAxes_22, squeeze(app.image22(app.slice_ind6,:,:)));
               else
                   app.UIAxes_22.CLim = [value app.MaxSpinner_22.Value];
                   imagesc(app.UIAxes_22, app.image22);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end             
        end

        % Value changed function: MinSpinner_23
        function MinSpinner_23ValueChanged(app, event)
            value = app.MinSpinner_23.Value;
            if value < app.MaxSpinner_23.Value
               if length(size(app.image23))>2
                   app.UIAxes_23.CLim = [value app.MaxSpinner_23.Value];
                   imagesc(app.UIAxes_23, squeeze(app.image23(app.slice_ind6,:,:)));
               else
                   app.UIAxes_23.CLim = [value app.MaxSpinner_23.Value];
                   imagesc(app.UIAxes_23, app.image23);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end             
        end

        % Value changed function: MinSpinner_24
        function MinSpinner_24ValueChanged(app, event)
            value = app.MinSpinner_24.Value;
            if value < app.MaxSpinner_24.Value
               if length(size(app.image24))>2
                   app.UIAxes_24.CLim = [value app.MaxSpinner_24.Value];
                   imagesc(app.UIAxes_24, squeeze(app.image24(app.slice_ind6,:,:)));
               else
                   app.UIAxes_24.CLim = [value app.MaxSpinner_24.Value];
                   imagesc(app.UIAxes_24, app.image24);
               end 
            else
                msgbox('Minimal intensity larger than max!');
            end             
        end

        % Menu selected function: LinkMenu
        function LinkMenuSelected(app, event)
            opt.Default = 'No';
            opt.Interpreter = 'tex';
            answer = questdlg('Link axes for all 4 panels?','Link axes','Yes','No',opt);
            % Handle response
            switch answer
                case 'Yes'
                    linkaxes([app.UIAxes_21,app.UIAxes_22,app.UIAxes_23,app.UIAxes_24],'xy');
                    linkaxes([app.UIAxes_17,app.UIAxes_18,app.UIAxes_19,app.UIAxes_20],'xy');
                    linkaxes([app.UIAxes_13,app.UIAxes_14,app.UIAxes_15,app.UIAxes_16],'xy');
                    linkaxes([app.UIAxes_9,app.UIAxes_10,app.UIAxes_11,app.UIAxes_12],'xy');
                    linkaxes([app.UIAxes_5,app.UIAxes_6,app.UIAxes_7,app.UIAxes_8],'xy');
                    linkaxes([app.UIAxes,app.UIAxes_2,app.UIAxes_3,app.UIAxes_4],'xy');
                case 'No'
                    
            end
        end

        % Menu selected function: AD7688Menu
        function AD7688MenuSelected(app, event)
           app.UIFigure.HandleVisibility = 'on';
           colormap gray;
           app.UIFigure.HandleVisibility = 'off';
           % select the directory
           [pathname] = uigetdir;
           h = waitbar(0,'Please wait... loading the data');
           % load the mus stack
           cd(pathname);
           FileInfo=dir('*mus_Stack.tif');
           if isempty(FileInfo)
               msgbox('Mus stack is missing in this folder!');
               waitbar(1/4);
           else
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image = zeros([length(info) size(temp)]);
                       app.image(u,:,:) = temp;
                   else
                       app.image(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(1/4);
               if length(size(app.image))>2
                   imagesc(app.UIAxes, squeeze(app.image(1,:,:)));
               else
                   imagesc(app.UIAxes, app.image);
               end
               app.SliceindexSpinner.Value = 1;
               app.MaxSpinner.Value = max(app.image(:));
               app.MinSpinner.Value = min(app.image(:));
               app.SliceindexSpinner.Limits = [1 size(app.image,1)];
               app.slice_ind = 1;
           end
           % load the mip stack
           FileInfo=dir('*mip_Stack.tif');
           if isempty(FileInfo)
               msgbox('MIP stack is missing in this folder!');
               waitbar(2/4);
           else
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image2 = zeros([length(info) size(temp)]);
                       app.image2(u,:,:) = temp;
                   else
                       app.image2(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(2/4);
               if length(size(app.image2))>2
                   imagesc(app.UIAxes_2, squeeze(app.image2(1,:,:)));
               else
                   imagesc(app.UIAxes_2, app.image2);
               end
               app.MaxSpinner_2.Value = max(app.image2(:));
               app.MinSpinner_2.Value = min(app.image2(:));
           end
           % load the mub stack
           FileInfo=dir('*mub_Stack.tif');
           if isempty(FileInfo)
               msgbox('Mub/Mus stack is missing in this folder!');
               waitbar(3/4);
           else           
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image3 = zeros([length(info) size(temp)]);
                       app.image3(u,:,:) = temp;
                   else
                       app.image3(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(3/4);
               if length(size(app.image3))>2
                   imagesc(app.UIAxes_3, squeeze(app.image3(1,:,:)));
               else
                   imagesc(app.UIAxes_3, app.image3);
               end
               app.MaxSpinner_3.Value = max(app.image3(:));
               app.MinSpinner_3.Value = min(app.image3(:));
           end
           % load the ret stack
           FileInfo=dir('*bfg_Stack.tif');
           if isempty(FileInfo)
               msgbox('BFG stack is missing in this folder!');
               waitbar(4/4);
           else           
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image4 = zeros([length(info) size(temp)]);
                       app.image4(u,:,:) = temp;
                   else
                       app.image4(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(4/4);
               if length(size(app.image4))>2
                   imagesc(app.UIAxes_4, squeeze(app.image4(1,:,:)));
               else
                   imagesc(app.UIAxes_4, app.image4);
               end
               app.MaxSpinner_4.Value = max(app.image4(:));
               app.MinSpinner_4.Value = min(app.image4(:));
           end
           close(h);
        end

        % Menu selected function: AD6692Menu
        function AD6692MenuSelected(app, event)
           app.UIFigure.HandleVisibility = 'on';
           colormap gray;
           app.UIFigure.HandleVisibility = 'off';
           % select the directory
           [pathname] = uigetdir;
           h = waitbar(0,'Please wait... loading the data');
           % load the mus stack
           cd(pathname);
           FileInfo=dir('*mus_Stack.tif');
           if isempty(FileInfo)
               msgbox('Mus stack is missing in this folder!');
               waitbar(1/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image5 = zeros([length(info) size(temp)]);
                       app.image5(u,:,:) = temp;
                   else
                       app.image5(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(1/4);
               if length(size(app.image5))>2
                   imagesc(app.UIAxes_5, squeeze(app.image5(1,:,:)));
               else
                   imagesc(app.UIAxes_5, app.image5);
               end
               app.SliceindexSpinner_2.Value = 1;
               app.MaxSpinner_5.Value = max(app.image5(:));
               app.MinSpinner_5.Value = min(app.image5(:));
               app.SliceindexSpinner_2.Limits = [1 size(app.image5,1)];
               app.slice_ind2 = 1;
           end
           % load the mip stack
           FileInfo=dir('*mip_Stack.tif');
           if isempty(FileInfo)
               msgbox('MIP stack is missing in this folder!');
               waitbar(2/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image6 = zeros([length(info) size(temp)]);
                       app.image6(u,:,:) = temp;
                   else
                       app.image6(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(2/4);
               if length(size(app.image6))>2
                   imagesc(app.UIAxes_6, squeeze(app.image6(1,:,:)));
               else
                   imagesc(app.UIAxes_6, app.image6);
               end
               app.MaxSpinner_6.Value = max(app.image6(:));
               app.MinSpinner_6.Value = min(app.image6(:));
           end
           % load the mub stack
           FileInfo=dir('*mub_Stack.tif');
           if isempty(FileInfo)
               msgbox('Mub/Mus stack is missing in this folder!');
               waitbar(3/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image7 = zeros([length(info) size(temp)]);
                       app.image7(u,:,:) = temp;
                   else
                       app.image7(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(3/4);
               if length(size(app.image7))>2
                   imagesc(app.UIAxes_7, squeeze(app.image7(1,:,:)));
               else
                   imagesc(app.UIAxes_7, app.image7);
               end
               app.MaxSpinner_7.Value = max(app.image7(:));
               app.MinSpinner_7.Value = min(app.image7(:));
           end
           % load the ret stack
           FileInfo=dir('*bfg_Stack.tif');
           if isempty(FileInfo)
               msgbox('BFG stack is missing in this folder!');
               waitbar(4/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image8 = zeros([length(info) size(temp)]);
                       app.image8(u,:,:) = temp;
                   else
                       app.image8(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(4/4);
               if length(size(app.image8))>2
                   imagesc(app.UIAxes_8, squeeze(app.image8(1,:,:)));
               else
                   imagesc(app.UIAxes_8, app.image8);
               end
               app.MaxSpinner_8.Value = max(app.image8(:));
               app.MinSpinner_8.Value = min(app.image8(:));
           end
           close(h);            
        end

        % Menu selected function: CTE7694Menu
        function CTE7694MenuSelected(app, event)
           app.UIFigure.HandleVisibility = 'on';
           colormap gray;
           app.UIFigure.HandleVisibility = 'off';
           % select the directory
           [pathname] = uigetdir;
           h = waitbar(0,'Please wait... loading the data');
           % load the mus stack
           cd(pathname);
           FileInfo=dir('*mus_Stack.tif');
           if isempty(FileInfo)
               msgbox('Mus stack is missing in this folder!');
               waitbar(1/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image9 = zeros([length(info) size(temp)]);
                       app.image9(u,:,:) = temp;
                   else
                       app.image9(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(1/4);
               if length(size(app.image9))>2
                   imagesc(app.UIAxes_9, squeeze(app.image9(1,:,:)));
               else
                   imagesc(app.UIAxes_9, app.image9);
               end
               app.SliceindexSpinner_3.Value = 1;
               app.MaxSpinner_9.Value = max(app.image9(:));
               app.MinSpinner_9.Value = min(app.image9(:));
               app.SliceindexSpinner_3.Limits = [1 size(app.image9,1)];
               app.slice_ind3 = 1;
           end
           % load the mip stack
           FileInfo=dir('*mip_Stack.tif');
           if isempty(FileInfo)
               msgbox('MIP stack is missing in this folder!');
               waitbar(2/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image10 = zeros([length(info) size(temp)]);
                       app.image10(u,:,:) = temp;
                   else
                       app.image10(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(2/4);
               if length(size(app.image10))>2
                   imagesc(app.UIAxes_10, squeeze(app.image10(1,:,:)));
               else
                   imagesc(app.UIAxes_10, app.image10);
               end
               app.MaxSpinner_10.Value = max(app.image10(:));
               app.MinSpinner_10.Value = min(app.image10(:));
           end
           % load the mub stack
           FileInfo=dir('*mub_Stack.tif');
           if isempty(FileInfo)
               msgbox('Mub/Mus stack is missing in this folder!');
               waitbar(3/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image11 = zeros([length(info) size(temp)]);
                       app.image11(u,:,:) = temp;
                   else
                       app.image11(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(3/4);
               if length(size(app.image11))>2
                   imagesc(app.UIAxes_11, squeeze(app.image11(1,:,:)));
               else
                   imagesc(app.UIAxes_11, app.image11);
               end
               app.MaxSpinner_11.Value = max(app.image11(:));
               app.MinSpinner_11.Value = min(app.image11(:));
           end
           % load the ret stack
           FileInfo=dir('*bfg_Stack.tif');
           if isempty(FileInfo)
               msgbox('BFG stack is missing in this folder!');
               waitbar(4/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image12 = zeros([length(info) size(temp)]);
                       app.image12(u,:,:) = temp;
                   else
                       app.image12(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(4/4);
               if length(size(app.image12))>2
                   imagesc(app.UIAxes_12, squeeze(app.image12(1,:,:)));
               else
                   imagesc(app.UIAxes_12, app.image12);
               end
               app.MaxSpinner_12.Value = max(app.image12(:));
               app.MinSpinner_12.Value = min(app.image12(:));
           end
           close(h);              
        end

        % Menu selected function: CTE8921Menu
        function CTE8921MenuSelected(app, event)
           app.UIFigure.HandleVisibility = 'on';
           colormap gray;
           app.UIFigure.HandleVisibility = 'off';
           % select the directory
           [pathname] = uigetdir;
           h = waitbar(0,'Please wait... loading the data');
           % load the mus stack
           cd(pathname);
           FileInfo=dir('*mus_Stack.tif');
           if isempty(FileInfo)
               msgbox('Mus stack is missing in this folder!');
               waitbar(1/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image13 = zeros([length(info) size(temp)]);
                       app.image13(u,:,:) = temp;
                   else
                       app.image13(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(1/4);
               if length(size(app.image13))>2
                   imagesc(app.UIAxes_13, squeeze(app.image13(1,:,:)));
               else
                   imagesc(app.UIAxes_13, app.image13);
               end
               app.SliceindexSpinner_4.Value = 1;
               app.MaxSpinner_13.Value = max(app.image13(:));
               app.MinSpinner_13.Value = min(app.image13(:));
               app.SliceindexSpinner_4.Limits = [1 size(app.image13,1)];
               app.slice_ind4 = 1;
           end
           % load the mip stack
           FileInfo=dir('*mip_Stack.tif');
           if isempty(FileInfo)
               msgbox('MIP stack is missing in this folder!');
               waitbar(2/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image14 = zeros([length(info) size(temp)]);
                       app.image14(u,:,:) = temp;
                   else
                       app.image14(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(2/4);
               if length(size(app.image14))>2
                   imagesc(app.UIAxes_14, squeeze(app.image14(1,:,:)));
               else
                   imagesc(app.UIAxes_14, app.image14);
               end
               app.MaxSpinner_14.Value = max(app.image14(:));
               app.MinSpinner_14.Value = min(app.image14(:));
           end
           % load the mub stack
           FileInfo=dir('*mub_Stack.tif');
           if isempty(FileInfo)
               msgbox('Mub/Mus stack is missing in this folder!');
               waitbar(3/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image15 = zeros([length(info) size(temp)]);
                       app.image15(u,:,:) = temp;
                   else
                       app.image15(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(3/4);
               if length(size(app.image15))>2
                   imagesc(app.UIAxes_15, squeeze(app.image15(1,:,:)));
               else
                   imagesc(app.UIAxes_15, app.image15);
               end
               app.MaxSpinner_15.Value = max(app.image15(:));
               app.MinSpinner_15.Value = min(app.image15(:));
           end
           % load the ret stack
           FileInfo=dir('*bfg_Stack.tif');
           if isempty(FileInfo)
               msgbox('BFG stack is missing in this folder!');
               waitbar(4/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image16 = zeros([length(info) size(temp)]);
                       app.image16(u,:,:) = temp;
                   else
                       app.image16(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(4/4);
               if length(size(app.image16))>2
                   imagesc(app.UIAxes_16, squeeze(app.image16(1,:,:)));
               else
                   imagesc(app.UIAxes_16, app.image16);
               end
               app.MaxSpinner_16.Value = max(app.image16(:));
               app.MinSpinner_16.Value = min(app.image16(:));
           end
           close(h);             
        end

        % Menu selected function: NCPTSD1Menu
        function NCPTSD1MenuSelected(app, event)
           app.UIFigure.HandleVisibility = 'on';
           colormap gray;
           app.UIFigure.HandleVisibility = 'off';
           % select the directory
           [pathname] = uigetdir;
           h = waitbar(0,'Please wait... loading the data');
           % load the mus stack
           cd(pathname);
           FileInfo=dir('*mus_Stack.tif');
           if isempty(FileInfo)
               msgbox('Mus stack is missing in this folder!');
               waitbar(1/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image17 = zeros([length(info) size(temp)]);
                       app.image17(u,:,:) = temp;
                   else
                       app.image17(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(1/4);
               if length(size(app.image17))>2
                   imagesc(app.UIAxes_17, squeeze(app.image17(1,:,:)));
               else
                   imagesc(app.UIAxes_17, app.image17);
               end
               app.SliceindexSpinner_5.Value = 1;
               app.MaxSpinner_17.Value = max(app.image17(:));
               app.MinSpinner_17.Value = min(app.image17(:));
               app.SliceindexSpinner_5.Limits = [1 size(app.image17,1)];
               app.slice_ind5 = 1;
           end
           % load the mip stack
           FileInfo=dir('*mip_Stack.tif');
           if isempty(FileInfo)
               msgbox('MIP stack is missing in this folder!');
               waitbar(2/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image18 = zeros([length(info) size(temp)]);
                       app.image18(u,:,:) = temp;
                   else
                       app.image18(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(2/4);
               if length(size(app.image18))>2
                   imagesc(app.UIAxes_18, squeeze(app.image18(1,:,:)));
               else
                   imagesc(app.UIAxes_18, app.image18);
               end
               app.MaxSpinner_18.Value = max(app.image18(:));
               app.MinSpinner_18.Value = min(app.image18(:));
           end
           % load the mub stack
           FileInfo=dir('*mub_Stack.tif');
           if isempty(FileInfo)
               msgbox('Mub/Mus stack is missing in this folder!');
               waitbar(3/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image19 = zeros([length(info) size(temp)]);
                       app.image19(u,:,:) = temp;
                   else
                       app.image19(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(3/4);
               if length(size(app.image19))>2
                   imagesc(app.UIAxes_19, squeeze(app.image19(1,:,:)));
               else
                   imagesc(app.UIAxes_19, app.image19);
               end
               app.MaxSpinner_19.Value = max(app.image19(:));
               app.MinSpinner_19.Value = min(app.image19(:));
           end
           % load the ret stack
           FileInfo=dir('*bfg_Stack.tif');
           if isempty(FileInfo)
               msgbox('BFG stack is missing in this folder!');
               waitbar(4/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image20 = zeros([length(info) size(temp)]);
                       app.image20(u,:,:) = temp;
                   else
                       app.image20(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(4/4);
               if length(size(app.image20))>2
                   imagesc(app.UIAxes_20, squeeze(app.image20(1,:,:)));
               else
                   imagesc(app.UIAxes_20, app.image20);
               end
               app.MaxSpinner_20.Value = max(app.image20(:));
               app.MinSpinner_20.Value = min(app.image20(:));
           end
           close(h);             
        end

        % Menu selected function: NC7478Menu
        function NC7478MenuSelected(app, event)
           app.UIFigure.HandleVisibility = 'on';
           colormap gray;
           app.UIFigure.HandleVisibility = 'off';
           % select the directory
           [pathname] = uigetdir;
           h = waitbar(0,'Please wait... loading the data');
           % load the mus stack
           cd(pathname);
           FileInfo=dir('*mus_Stack.tif');
           if isempty(FileInfo)
               msgbox('Mus stack is missing in this folder!');
               waitbar(1/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image21 = zeros([length(info) size(temp)]);
                       app.image21(u,:,:) = temp;
                   else
                       app.image21(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(1/4);
               if length(size(app.image21))>2
                   imagesc(app.UIAxes_21, squeeze(app.image21(1,:,:)));
               else
                   imagesc(app.UIAxes_21, app.image21);
               end
               app.SliceindexSpinner_6.Value = 1;
               app.MaxSpinner_21.Value = max(app.image21(:));
               app.MinSpinner_21.Value = min(app.image21(:));
               app.SliceindexSpinner_6.Limits = [1 size(app.image21,1)];
               app.slice_ind6 = 1;
           end
           % load the mip stack
           FileInfo=dir('*mip_Stack.tif');
           if isempty(FileInfo)
               msgbox('MIP stack is missing in this folder!');
               waitbar(2/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image22 = zeros([length(info) size(temp)]);
                       app.image22(u,:,:) = temp;
                   else
                       app.image22(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(2/4);
               if length(size(app.image22))>2
                   imagesc(app.UIAxes_22, squeeze(app.image22(1,:,:)));
               else
                   imagesc(app.UIAxes_22, app.image22);
               end
               app.MaxSpinner_22.Value = max(app.image22(:));
               app.MinSpinner_22.Value = min(app.image22(:));
           end
           % load the mub stack
           FileInfo=dir('*mub_Stack.tif');
           if isempty(FileInfo)
               msgbox('Mub/Mus stack is missing in this folder!');
               waitbar(3/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image23 = zeros([length(info) size(temp)]);
                       app.image23(u,:,:) = temp;
                   else
                       app.image23(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(3/4);
               if length(size(app.image23))>2
                   imagesc(app.UIAxes_23, squeeze(app.image23(1,:,:)));
               else
                   imagesc(app.UIAxes_23, app.image23);
               end
               app.MaxSpinner_23.Value = max(app.image23(:));
               app.MinSpinner_23.Value = min(app.image23(:));
           end
           % load the ret stack
           FileInfo=dir('*bfg_Stack.tif');
           if isempty(FileInfo)
               msgbox('BFG stack is missing in this folder!');
               waitbar(4/4);
           else            
               filename=FileInfo.name;
               info = imfinfo([pathname, '\', filename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread([pathname, '\', filename],1);
                       app.image24 = zeros([length(info) size(temp)]);
                       app.image24(u,:,:) = temp;
                   else
                       app.image24(u,:,:) = imread([pathname, '\', filename],u);
                   end
               end
               waitbar(4/4);
               if length(size(app.image24))>2
                   imagesc(app.UIAxes_24, squeeze(app.image24(1,:,:)));
               else
                   imagesc(app.UIAxes_24, app.image24);
               end
               app.MaxSpinner_24.Value = max(app.image24(:));
               app.MinSpinner_24.Value = min(app.image24(:));
           end
           close(h);             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadImageMenu = uimenu(app.UIFigure);
            app.LoadImageMenu.Text = 'Load Image';
            app.SelectsubjectfolderMenu = uimenu(app.LoadImageMenu);
            app.SelectsubjectfolderMenu.Text = 'Select subject folder';
            app.AD7688Menu = uimenu(app.SelectsubjectfolderMenu);
            app.AD7688Menu.MenuSelectedFcn = createCallbackFcn(app, @AD7688MenuSelected, true);
            app.AD7688Menu.Text = 'AD 7688';
            app.AD6692Menu = uimenu(app.SelectsubjectfolderMenu);
            app.AD6692Menu.MenuSelectedFcn = createCallbackFcn(app, @AD6692MenuSelected, true);
            app.AD6692Menu.Text = 'AD 6692';
            app.CTE7694Menu = uimenu(app.SelectsubjectfolderMenu);
            app.CTE7694Menu.MenuSelectedFcn = createCallbackFcn(app, @CTE7694MenuSelected, true);
            app.CTE7694Menu.Text = 'CTE 7694';
            app.CTE8921Menu = uimenu(app.SelectsubjectfolderMenu);
            app.CTE8921Menu.MenuSelectedFcn = createCallbackFcn(app, @CTE8921MenuSelected, true);
            app.CTE8921Menu.Text = 'CTE 8921';
            app.NCPTSD1Menu = uimenu(app.SelectsubjectfolderMenu);
            app.NCPTSD1Menu.MenuSelectedFcn = createCallbackFcn(app, @NCPTSD1MenuSelected, true);
            app.NCPTSD1Menu.Text = 'NC PTSD1';
            app.NC7478Menu = uimenu(app.SelectsubjectfolderMenu);
            app.NC7478Menu.MenuSelectedFcn = createCallbackFcn(app, @NC7478MenuSelected, true);
            app.NC7478Menu.Text = 'NC 7478';
            app.LinkAxesMenu = uimenu(app.UIFigure);
            app.LinkAxesMenu.Text = 'Link Axes';
            app.LinkMenu = uimenu(app.LinkAxesMenu);
            app.LinkMenu.MenuSelectedFcn = createCallbackFcn(app, @LinkMenuSelected, true);
            app.LinkMenu.Text = 'Link';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.UIFigure);
            title(app.UIAxes_4, 'Title')
            xlabel(app.UIAxes_4, 'X')
            ylabel(app.UIAxes_4, 'Y')
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_4.Visible = 'off';
            app.UIAxes_5 = uiaxes(app.UIFigure);
            title(app.UIAxes_5, 'Title')
            xlabel(app.UIAxes_5, 'X')
            ylabel(app.UIAxes_5, 'Y')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_5.Visible = 'off';
            app.UIAxes_6 = uiaxes(app.UIFigure);
            title(app.UIAxes_6, 'Title')
            xlabel(app.UIAxes_6, 'X')
            ylabel(app.UIAxes_6, 'Y')
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_6.Visible = 'off';
            app.UIAxes_7 = uiaxes(app.UIFigure);
            title(app.UIAxes_7, 'Title')
            xlabel(app.UIAxes_7, 'X')
            ylabel(app.UIAxes_7, 'Y')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_7.Visible = 'off';
            app.UIAxes_8 = uiaxes(app.UIFigure);
            title(app.UIAxes_8, 'Title')
            xlabel(app.UIAxes_8, 'X')
            ylabel(app.UIAxes_8, 'Y')
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_8.Visible = 'off';
            app.UIAxes_9 = uiaxes(app.UIFigure);
            title(app.UIAxes_9, 'Title')
            xlabel(app.UIAxes_9, 'X')
            ylabel(app.UIAxes_9, 'Y')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes_9.Visible = 'off';
            app.UIAxes_10 = uiaxes(app.UIFigure);
            title(app.UIAxes_10, 'Title')
            xlabel(app.UIAxes_10, 'X')
            ylabel(app.UIAxes_10, 'Y')
            zlabel(app.UIAxes_10, 'Z')
            app.UIAxes_10.Visible = 'off';
            app.UIAxes_11 = uiaxes(app.UIFigure);
            title(app.UIAxes_11, 'Title')
            xlabel(app.UIAxes_11, 'X')
            ylabel(app.UIAxes_11, 'Y')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes_11.Visible = 'off';
            app.UIAxes_12 = uiaxes(app.UIFigure);
            title(app.UIAxes_12, 'Title')
            xlabel(app.UIAxes_12, 'X')
            ylabel(app.UIAxes_12, 'Y')
            zlabel(app.UIAxes_12, 'Z')
            app.UIAxes_12.Visible = 'off';
            app.UIAxes_13 = uiaxes(app.UIFigure);
            title(app.UIAxes_13, 'Title')
            xlabel(app.UIAxes_13, 'X')
            ylabel(app.UIAxes_13, 'Y')
            zlabel(app.UIAxes_13, 'Z')
            app.UIAxes_13.Visible = 'off';
            app.UIAxes_14 = uiaxes(app.UIFigure);
            title(app.UIAxes_14, 'Title')
            xlabel(app.UIAxes_14, 'X')
            ylabel(app.UIAxes_14, 'Y')
            zlabel(app.UIAxes_14, 'Z')
            app.UIAxes_14.Visible = 'off';
            app.UIAxes_15 = uiaxes(app.UIFigure);
            title(app.UIAxes_15, 'Title')
            xlabel(app.UIAxes_15, 'X')
            ylabel(app.UIAxes_15, 'Y')
            zlabel(app.UIAxes_15, 'Z')
            app.UIAxes_15.Visible = 'off';
            app.UIAxes_16 = uiaxes(app.UIFigure);
            title(app.UIAxes_16, 'Title')
            xlabel(app.UIAxes_16, 'X')
            ylabel(app.UIAxes_16, 'Y')
            zlabel(app.UIAxes_16, 'Z')
            app.UIAxes_16.Visible = 'off';
            app.UIAxes_17 = uiaxes(app.UIFigure);
            title(app.UIAxes_17, 'Title')
            xlabel(app.UIAxes_17, 'X')
            ylabel(app.UIAxes_17, 'Y')
            zlabel(app.UIAxes_17, 'Z')
            app.UIAxes_17.Visible = 'off';
            app.UIAxes_18 = uiaxes(app.UIFigure);
            title(app.UIAxes_18, 'Title')
            xlabel(app.UIAxes_18, 'X')
            ylabel(app.UIAxes_18, 'Y')
            zlabel(app.UIAxes_18, 'Z')
            app.UIAxes_18.Visible = 'off';
            app.UIAxes_19 = uiaxes(app.UIFigure);
            title(app.UIAxes_19, 'Title')
            xlabel(app.UIAxes_19, 'X')
            ylabel(app.UIAxes_19, 'Y')
            zlabel(app.UIAxes_19, 'Z')
            app.UIAxes_19.Visible = 'off';
            app.UIAxes_20 = uiaxes(app.UIFigure);
            title(app.UIAxes_20, 'Title')
            xlabel(app.UIAxes_20, 'X')
            ylabel(app.UIAxes_20, 'Y')
            zlabel(app.UIAxes_20, 'Z')
            app.UIAxes_20.Visible = 'off';
            app.UIAxes_21 = uiaxes(app.UIFigure);
            title(app.UIAxes_21, 'Title')
            xlabel(app.UIAxes_21, 'X')
            ylabel(app.UIAxes_21, 'Y')
            zlabel(app.UIAxes_21, 'Z')
            app.UIAxes_21.Visible = 'off';
            app.UIAxes_22 = uiaxes(app.UIFigure);
            title(app.UIAxes_22, 'Title')
            xlabel(app.UIAxes_22, 'X')
            ylabel(app.UIAxes_22, 'Y')
            zlabel(app.UIAxes_22, 'Z')
            app.UIAxes_22.Visible = 'off';
            app.UIAxes_23 = uiaxes(app.UIFigure);
            title(app.UIAxes_23, 'Title')
            xlabel(app.UIAxes_23, 'X')
            ylabel(app.UIAxes_23, 'Y')
            zlabel(app.UIAxes_23, 'Z')
            app.UIAxes_23.Visible = 'off';
            app.UIAxes_24 = uiaxes(app.UIFigure);
            title(app.UIAxes_24, 'Title')
            xlabel(app.UIAxes_24, 'X')
            ylabel(app.UIAxes_24, 'Y')
            zlabel(app.UIAxes_24, 'Z')
            app.UIAxes_24.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.UIFigure);
            title(app.UIAxes_3, 'Title')
            xlabel(app.UIAxes_3, 'X')
            ylabel(app.UIAxes_3, 'Y')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_3.Visible = 'off';
            app.MaxSpinnerLabel = uilabel(app.UIFigure);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.UIFigure);
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxSpinnerValueChanged, true);
            app.MinSpinnerLabel = uilabel(app.UIFigure);
            app.MinSpinnerLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.UIFigure);
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @MinSpinnerValueChanged, true);
            app.musLabel = uilabel(app.UIFigure);
            app.musLabel.Text = 'mus';
            app.MIPLabel = uilabel(app.UIFigure);
            app.MIPLabel.Text = 'MIP';
            app.mubmusLabel = uilabel(app.UIFigure);
            app.mubmusLabel.Text = {'mub/'; 'mus'};
            app.retLabel = uilabel(app.UIFigure);
            app.retLabel.Text = 'ret';
            app.MinSpinner_2Label = uilabel(app.UIFigure);
            app.MinSpinner_2Label.Text = 'Min';
            app.MinSpinner_2 = uispinner(app.UIFigure);
            app.MinSpinner_2.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_2ValueChanged, true);
            app.MaxSpinner_2Label = uilabel(app.UIFigure);
            app.MaxSpinner_2Label.Text = 'Max';
            app.MaxSpinner_2 = uispinner(app.UIFigure);
            app.MaxSpinner_2.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_2ValueChanged, true);
            app.MinSpinner_4Label = uilabel(app.UIFigure);
            app.MinSpinner_4Label.Text = 'Min';
            app.MinSpinner_4 = uispinner(app.UIFigure);
            app.MinSpinner_4.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_4ValueChanged, true);
            app.MaxSpinner_4Label = uilabel(app.UIFigure);
            app.MaxSpinner_4Label.Text = 'Max';
            app.MaxSpinner_4 = uispinner(app.UIFigure);
            app.MaxSpinner_4.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_4ValueChanged, true);
            app.AD7688Label = uilabel(app.UIFigure);
            app.AD7688Label.Text = 'AD 7688';
            app.MinSpinner_5Label = uilabel(app.UIFigure);
            app.MinSpinner_5Label.Text = 'Min';
            app.MinSpinner_5 = uispinner(app.UIFigure);
            app.MinSpinner_5.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_5ValueChanged, true);
            app.MaxSpinner_5Label = uilabel(app.UIFigure);
            app.MaxSpinner_5Label.Text = 'Max';
            app.MaxSpinner_5 = uispinner(app.UIFigure);
            app.MaxSpinner_5.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_5ValueChanged, true);
            app.MinSpinner_6Label = uilabel(app.UIFigure);
            app.MinSpinner_6Label.Text = 'Min';
            app.MinSpinner_6 = uispinner(app.UIFigure);
            app.MinSpinner_6.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_6ValueChanged, true);
            app.MaxSpinner_6Label = uilabel(app.UIFigure);
            app.MaxSpinner_6Label.Text = 'Max';
            app.MaxSpinner_6 = uispinner(app.UIFigure);
            app.MaxSpinner_6.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_6ValueChanged, true);
            app.MinSpinner_7Label = uilabel(app.UIFigure);
            app.MinSpinner_7Label.Text = 'Min';
            app.MinSpinner_7 = uispinner(app.UIFigure);
            app.MinSpinner_7.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_7ValueChanged, true);
            app.MaxSpinner_7Label = uilabel(app.UIFigure);
            app.MaxSpinner_7Label.Text = 'Max';
            app.MaxSpinner_7 = uispinner(app.UIFigure);
            app.MaxSpinner_7.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_7ValueChanged, true);
            app.MinSpinner_8Label = uilabel(app.UIFigure);
            app.MinSpinner_8Label.Text = 'Min';
            app.MinSpinner_8 = uispinner(app.UIFigure);
            app.MinSpinner_8.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_8ValueChanged, true);
            app.MinSpinner_3Label = uilabel(app.UIFigure);
            app.MinSpinner_3Label.Text = 'Min';
            app.MinSpinner_3 = uispinner(app.UIFigure);
            app.MinSpinner_3.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_3ValueChanged, true);
            app.MaxSpinner_3Label = uilabel(app.UIFigure);
            app.MaxSpinner_3Label.Text = 'Max';
            app.MaxSpinner_3 = uispinner(app.UIFigure);
            app.MaxSpinner_3.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_3ValueChanged, true);
            app.MaxSpinner_8Label = uilabel(app.UIFigure);
            app.MaxSpinner_8Label.Text = 'Max';
            app.MaxSpinner_8 = uispinner(app.UIFigure);
            app.MaxSpinner_8.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_8ValueChanged, true);
            app.MinSpinner_9Label = uilabel(app.UIFigure);
            app.MinSpinner_9Label.Text = 'Min';
            app.MinSpinner_9 = uispinner(app.UIFigure);
            app.MinSpinner_9.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_9ValueChanged, true);
            app.MaxSpinner_9Label = uilabel(app.UIFigure);
            app.MaxSpinner_9Label.Text = 'Max';
            app.MaxSpinner_9 = uispinner(app.UIFigure);
            app.MaxSpinner_9.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_9ValueChanged, true);
            app.MinSpinner_10Label = uilabel(app.UIFigure);
            app.MinSpinner_10Label.Text = 'Min';
            app.MinSpinner_10 = uispinner(app.UIFigure);
            app.MinSpinner_10.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_10ValueChanged, true);
            app.MaxSpinner_10Label = uilabel(app.UIFigure);
            app.MaxSpinner_10Label.Text = 'Max';
            app.MaxSpinner_10 = uispinner(app.UIFigure);
            app.MaxSpinner_10.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_10ValueChanged, true);
            app.MinSpinner_11Label = uilabel(app.UIFigure);
            app.MinSpinner_11Label.Text = 'Min';
            app.MinSpinner_11 = uispinner(app.UIFigure);
            app.MinSpinner_11.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_11ValueChanged, true);
            app.MaxSpinner_11Label = uilabel(app.UIFigure);
            app.MaxSpinner_11Label.Text = 'Max';
            app.MaxSpinner_11 = uispinner(app.UIFigure);
            app.MaxSpinner_11.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_11ValueChanged, true);
            app.MinSpinner_12Label = uilabel(app.UIFigure);
            app.MinSpinner_12Label.Text = 'Min';
            app.MinSpinner_12 = uispinner(app.UIFigure);
            app.MinSpinner_12.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_12ValueChanged, true);
            app.MaxSpinner_12Label = uilabel(app.UIFigure);
            app.MaxSpinner_12Label.Text = 'Max';
            app.MaxSpinner_12 = uispinner(app.UIFigure);
            app.MaxSpinner_12.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_12ValueChanged, true);
            app.MinSpinner_13Label = uilabel(app.UIFigure);
            app.MinSpinner_13Label.Text = 'Min';
            app.MinSpinner_13 = uispinner(app.UIFigure);
            app.MinSpinner_13.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_13ValueChanged, true);
            app.MaxSpinner_13Label = uilabel(app.UIFigure);
            app.MaxSpinner_13Label.Text = 'Max';
            app.MaxSpinner_13 = uispinner(app.UIFigure);
            app.MaxSpinner_13.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_13ValueChanged, true);
            app.MinSpinner_14Label = uilabel(app.UIFigure);
            app.MinSpinner_14Label.Text = 'Min';
            app.MinSpinner_14 = uispinner(app.UIFigure);
            app.MinSpinner_14.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_14ValueChanged, true);
            app.MaxSpinner_14Label = uilabel(app.UIFigure);
            app.MaxSpinner_14Label.Text = 'Max';
            app.MaxSpinner_14 = uispinner(app.UIFigure);
            app.MaxSpinner_14.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_14ValueChanged, true);
            app.MinSpinner_15Label = uilabel(app.UIFigure);
            app.MinSpinner_15Label.Text = 'Min';
            app.MinSpinner_15 = uispinner(app.UIFigure);
            app.MinSpinner_15.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_15ValueChanged, true);
            app.MaxSpinner_15Label = uilabel(app.UIFigure);
            app.MaxSpinner_15Label.Text = 'Max';
            app.MaxSpinner_15 = uispinner(app.UIFigure);
            app.MaxSpinner_15.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_15ValueChanged, true);
            app.MinSpinner_16Label = uilabel(app.UIFigure);
            app.MinSpinner_16Label.Text = 'Min';
            app.MinSpinner_16 = uispinner(app.UIFigure);
            app.MinSpinner_16.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_16ValueChanged, true);
            app.MaxSpinner_16Label = uilabel(app.UIFigure);
            app.MaxSpinner_16Label.Text = 'Max';
            app.MaxSpinner_16 = uispinner(app.UIFigure);
            app.MaxSpinner_16.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_16ValueChanged, true);
            app.MinSpinner_17Label = uilabel(app.UIFigure);
            app.MinSpinner_17Label.Text = 'Min';
            app.MinSpinner_17 = uispinner(app.UIFigure);
            app.MinSpinner_17.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_17ValueChanged, true);
            app.MaxSpinner_17Label = uilabel(app.UIFigure);
            app.MaxSpinner_17Label.Text = 'Max';
            app.MaxSpinner_17 = uispinner(app.UIFigure);
            app.MaxSpinner_17.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_17ValueChanged, true);
            app.MinSpinner_18Label = uilabel(app.UIFigure);
            app.MinSpinner_18Label.Text = 'Min';
            app.MinSpinner_18 = uispinner(app.UIFigure);
            app.MinSpinner_18.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_18ValueChanged, true);
            app.MaxSpinner_18Label = uilabel(app.UIFigure);
            app.MaxSpinner_18Label.Text = 'Max';
            app.MaxSpinner_18 = uispinner(app.UIFigure);
            app.MaxSpinner_18.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_18ValueChanged, true);
            app.MinSpinner_19Label = uilabel(app.UIFigure);
            app.MinSpinner_19Label.Text = 'Min';
            app.MinSpinner_19 = uispinner(app.UIFigure);
            app.MinSpinner_19.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_19ValueChanged, true);
            app.MaxSpinner_19Label = uilabel(app.UIFigure);
            app.MaxSpinner_19Label.Text = 'Max';
            app.MaxSpinner_19 = uispinner(app.UIFigure);
            app.MaxSpinner_19.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_19ValueChanged, true);
            app.MinSpinner_20Label = uilabel(app.UIFigure);
            app.MinSpinner_20Label.Text = 'Min';
            app.MinSpinner_20 = uispinner(app.UIFigure);
            app.MinSpinner_20.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_20ValueChanged, true);
            app.MaxSpinner_20Label = uilabel(app.UIFigure);
            app.MaxSpinner_20Label.Text = 'Max';
            app.MaxSpinner_20 = uispinner(app.UIFigure);
            app.MaxSpinner_20.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_20ValueChanged, true);
            app.MinSpinner_21Label = uilabel(app.UIFigure);
            app.MinSpinner_21Label.Text = 'Min';
            app.MinSpinner_21 = uispinner(app.UIFigure);
            app.MinSpinner_21.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_21ValueChanged, true);
            app.MaxSpinner_21Label = uilabel(app.UIFigure);
            app.MaxSpinner_21Label.Text = 'Max';
            app.MaxSpinner_21 = uispinner(app.UIFigure);
            app.MaxSpinner_21.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_21ValueChanged, true);
            app.MinSpinner_22Label = uilabel(app.UIFigure);
            app.MinSpinner_22Label.Text = 'Min';
            app.MinSpinner_22 = uispinner(app.UIFigure);
            app.MinSpinner_22.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_22ValueChanged, true);
            app.MaxSpinner_22Label = uilabel(app.UIFigure);
            app.MaxSpinner_22Label.Text = 'Max';
            app.MaxSpinner_22 = uispinner(app.UIFigure);
            app.MaxSpinner_22.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_22ValueChanged, true);
            app.MinSpinner_23Label = uilabel(app.UIFigure);
            app.MinSpinner_23Label.Text = 'Min';
            app.MinSpinner_23 = uispinner(app.UIFigure);
            app.MinSpinner_23.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_23ValueChanged, true);
            app.MaxSpinner_23Label = uilabel(app.UIFigure);
            app.MaxSpinner_23Label.Text = 'Max';
            app.MaxSpinner_23 = uispinner(app.UIFigure);
            app.MaxSpinner_23.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_23ValueChanged, true);
            app.MinSpinner_24Label = uilabel(app.UIFigure);
            app.MinSpinner_24Label.Text = 'Min';
            app.MinSpinner_24 = uispinner(app.UIFigure);
            app.MinSpinner_24.ValueChangedFcn = createCallbackFcn(app, @MinSpinner_24ValueChanged, true);
            app.MaxSpinner_24Label = uilabel(app.UIFigure);
            app.MaxSpinner_24Label.Text = 'Max';
            app.MaxSpinner_24 = uispinner(app.UIFigure);
            app.MaxSpinner_24.ValueChangedFcn = createCallbackFcn(app, @MaxSpinner_24ValueChanged, true);
            app.AD6692Label = uilabel(app.UIFigure);
            app.AD6692Label.Text = 'AD 6692';
            app.CTE7694Label = uilabel(app.UIFigure);
            app.CTE7694Label.Text = 'CTE 7694';
            app.CTE8921Label = uilabel(app.UIFigure);
            app.CTE8921Label.Text = 'CTE 8921';
            app.NCPTSD1Label = uilabel(app.UIFigure);
            app.NCPTSD1Label.Text = 'NC PTSD1';
            app.NC7478Label = uilabel(app.UIFigure);
            app.NC7478Label.Text = 'NC 7478';
            app.SliceindexSpinner_2Label = uilabel(app.UIFigure);
            app.SliceindexSpinner_2Label.Text = 'Slice index';
            app.SliceindexSpinner_2 = uispinner(app.UIFigure);
            app.SliceindexSpinner_2.ValueChangedFcn = createCallbackFcn(app, @SliceindexSpinner_2ValueChanged, true);
            app.SliceindexSpinner_3Label = uilabel(app.UIFigure);
            app.SliceindexSpinner_3Label.Text = 'Slice index';
            app.SliceindexSpinner_3 = uispinner(app.UIFigure);
            app.SliceindexSpinner_3.ValueChangedFcn = createCallbackFcn(app, @SliceindexSpinner_3ValueChanged, true);
            app.SliceindexSpinner_4Label = uilabel(app.UIFigure);
            app.SliceindexSpinner_4Label.Text = 'Slice index';
            app.SliceindexSpinner_4 = uispinner(app.UIFigure);
            app.SliceindexSpinner_4.ValueChangedFcn = createCallbackFcn(app, @SliceindexSpinner_4ValueChanged, true);
            app.SliceindexSpinner_5Label = uilabel(app.UIFigure);
            app.SliceindexSpinner_5Label.Text = 'Slice index';
            app.SliceindexSpinner_5 = uispinner(app.UIFigure);
            app.SliceindexSpinner_5.ValueChangedFcn = createCallbackFcn(app, @SliceindexSpinner_5ValueChanged, true);
            app.SliceindexSpinner_6Label = uilabel(app.UIFigure);
            app.SliceindexSpinner_6Label.Text = 'Slice index';
            app.SliceindexSpinner_6 = uispinner(app.UIFigure);
            app.SliceindexSpinner_6.ValueChangedFcn = createCallbackFcn(app, @SliceindexSpinner_6ValueChanged, true);
            app.SliceindexSpinner_7Label = uilabel(app.UIFigure);
            app.SliceindexSpinner_7Label.Text = 'Slice index';
            app.SliceindexSpinner = uispinner(app.UIFigure);
            app.SliceindexSpinner.ValueChangedFcn = createCallbackFcn(app, @SliceindexSpinnerValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end