classdef toy_dataset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TilenameEditField         matlab.ui.control.EditField
        TilenameEditFieldLabel    matlab.ui.control.Label
        MaxintensitySpinner       matlab.ui.control.Spinner
        MaxintensitySpinnerLabel  matlab.ui.control.Label
        FinishdiscussingButton    matlab.ui.control.Button
        LoaddataButton            matlab.ui.control.Button
        PAPanel                   matlab.ui.container.Panel
        UIAxes3                   matlab.ui.control.UIAxes
        JYPanel                   matlab.ui.container.Panel
        UIAxes2                   matlab.ui.control.UIAxes
        ANPanel                   matlab.ui.container.Panel
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image
        image_DS
        tilename
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)

           clc;
           addpath(genpath(pwd));      
           
           % re-initialize all the UI axes
           cla(app.UIAxes2);
           cla(app.UIAxes3);
           cla(app.UIAxes);
           title(app.UIAxes, []);
           xlabel(app.UIAxes, []);
           ylabel(app.UIAxes, []);
           app.UIAxes.XAxis.TickLabels = {};
           app.UIAxes.YAxis.TickLabels = {};
           title(app.UIAxes2, []);
           xlabel(app.UIAxes2, []);
           ylabel(app.UIAxes2, []);
           app.UIAxes2.XAxis.TickLabels = {};
           app.UIAxes2.YAxis.TickLabels = {};
           title(app.UIAxes3, []);
           xlabel(app.UIAxes3, []);
           ylabel(app.UIAxes3, []);
           app.UIAxes3.XAxis.TickLabels = {};
           app.UIAxes3.YAxis.TickLabels = {};
           
           % randomly read a tile from the directory
           filename = dir('.\annotated_AN\annotated\*.tif');
           
           if ~exist([pwd 'discussed'],'dir')
               mkdir discussed;
           end
           
           if isempty(filename)
               msgbox('Image file is missing in this folder! Check directory and reload.');
           else
               n_file = length(filename);
               file_id = randi(n_file,1);
               app.tilename = filename(file_id).name;
               info = imfinfo(['.\annotated_AN\annotated\' app.tilename]);
               for u = 1:length(info)
                   if u == 1
                       temp = imread(['.\annotated_AN\annotated\' app.tilename],1);
                       app.image = zeros([length(info) size(temp)]);
                       app.image(u,:,:) = temp;
                   else
                       app.image(u,:,:) = imread(['.\annotated_AN\annotated\' app.tilename],u);
                   end
               end
               if length(size(app.image))>2
                   % generate focusing stack
                   h = waitbar(0,'Generating defocused stack ...');
                   app.image_DS = fstack(squeeze(num2cell(uint16(permute(app.image, [2 3 1])),[1 2])),...
                       'dilatesize', 9,'blendsize', 9);
                   waitbar(1,h,'Finished.');
                   close(h);
                   fDS = imagesc(app.UIAxes,mat2gray(app.image_DS));
                   app.UIAxes.Colormap = gray;
                   % Set limits of axes
                   app.MaxintensitySpinner.Value = 25;
                   app.UIAxes.XLim = [0 fDS.XData(2)];
                   app.UIAxes.YLim = [0 fDS.YData(2)];
                   app.UIAxes.CLim = [0 0.25];
                   fDS2 = imagesc(app.UIAxes2,mat2gray(app.image_DS));
                   app.UIAxes2.Colormap = gray;
                   app.UIAxes2.XLim = [0 fDS2.XData(2)];
                   app.UIAxes2.YLim = [0 fDS2.YData(2)];
                   app.UIAxes2.CLim = [0 0.25];
                   fDS3 = imagesc(app.UIAxes3,mat2gray(app.image_DS));
                   app.UIAxes3.Colormap = gray;
                   app.UIAxes3.XLim = [0 fDS3.XData(2)];
                   app.UIAxes3.YLim = [0 fDS3.YData(2)];
                   app.UIAxes3.CLim = [0 0.25];
               end
           end              
           
           % load ROI information
           tile_seg = strsplit(app.tilename,'.');
           tile_id = tile_seg{1};
           
           app.TilenameEditField.Value = tile_id;
           
           S_AN = load(['.\annotated_AN\annotated\',tile_id,'.mat']);
           S_JY = load(['.\annotated_JY\',tile_id,'.mat']);
           S_PA = load(['.\annotated_PA\',tile_id,'.mat']);
           
           % draw ROI on each panel
           v_AN = S_AN.UIoutput.vesicle;
           for i = 1:length(v_AN)
               if ~isempty(v_AN{i})
                   drawline(app.UIAxes,'Color','r','Position',v_AN{i});
               end
           end
           b_AN = S_AN.UIoutput.balloon;
           for i = 1:length(b_AN)
               if ~isempty(b_AN{i})
                   drawline(app.UIAxes,'Color','b','Position',b_AN{i});
               end
           end
           m_AN = S_AN.UIoutput.myelin;
           for i = 1:length(m_AN)
               if ~isempty(m_AN{i})
                   drawline(app.UIAxes,'Color','g','Position',m_AN{i});
               end
           end
           
           v_JY = S_JY.UIoutput.vesicle;
           b_JY = S_JY.UIoutput.balloon;  
           m_JY = S_JY.UIoutput.myelin;
           for i = 1:length(v_JY)
               if ~isempty(v_JY{i})
                   drawline(app.UIAxes2,'Color','r','Position',v_JY{i});
               end
           end
           for i = 1:length(b_JY)
               if ~isempty(b_JY{i})
                   drawline(app.UIAxes2,'Color','b','Position',b_JY{i});
               end
           end
           for i = 1:length(m_JY)
               if ~isempty(m_JY{i})
                   drawline(app.UIAxes2,'Color','g','Position',m_JY{i});
               end
           end
         
           v_PA = S_PA.UIoutput.vesicle;
           b_PA = S_PA.UIoutput.balloon;
           m_PA = S_PA.UIoutput.myelin;
           for i = 1:length(v_PA)
               if ~isempty(v_PA{i})
                   drawline(app.UIAxes3,'Color','r','Position',v_PA{i});
               end
           end
           for i = 1:length(b_PA)
               if ~isempty(b_PA{i})
                   drawline(app.UIAxes3,'Color','b','Position',b_PA{i});
               end
           end
           for i = 1:length(m_PA)
               if ~isempty(m_PA{i})
                   drawline(app.UIAxes3,'Color','g','Position',m_PA{i});
               end
           end
        end

        % Button pushed function: FinishdiscussingButton
        function FinishdiscussingButtonPushed(app, event)
            movefile(['.\annotated_AN\annotated\' app.tilename], '.\discussed');
        end

        % Value changed function: MaxintensitySpinner
        function MaxintensitySpinnerValueChanged(app, event)
            value = app.MaxintensitySpinner.Value;
            app.UIAxes.NextPlot = 'replacechildren';
            f = imagesc(app.UIAxes,mat2gray(app.image_DS));
            app.UIAxes.Colormap = gray;
            % Set limits of axes
            app.UIAxes.XLim = [0 f.XData(2)];
            app.UIAxes.YLim = [0 f.YData(2)];
            app.UIAxes.CLim = [0 value/100];
            f2 = imagesc(app.UIAxes2,mat2gray(app.image_DS));
            app.UIAxes2.Colormap = gray;
            % Set limits of axes
            app.UIAxes2.XLim = [0 f2.XData(2)];
            app.UIAxes2.YLim = [0 f2.YData(2)];
            app.UIAxes2.CLim = [0 value/100];
            f3 = imagesc(app.UIAxes3,mat2gray(app.image_DS));
            app.UIAxes3.Colormap = gray;
            % Set limits of axes
            app.UIAxes3.XLim = [0 f3.XData(2)];
            app.UIAxes3.YLim = [0 f3.YData(2)];
            app.UIAxes3.CLim = [0 value/100];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ANPanel = uipanel(app.UIFigure);
            app.ANPanel.Title = 'AN';
            app.UIAxes = uiaxes(app.ANPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.JYPanel = uipanel(app.UIFigure);
            app.JYPanel.Title = 'JY';
            app.UIAxes2 = uiaxes(app.JYPanel);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.PAPanel = uipanel(app.UIFigure);
            app.PAPanel.Title = 'PA';
            app.UIAxes3 = uiaxes(app.PAPanel);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.Visible = 'off';
            app.LoaddataButton = uibutton(app.UIFigure, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.FinishdiscussingButton = uibutton(app.UIFigure, 'push');
            app.FinishdiscussingButton.ButtonPushedFcn = createCallbackFcn(app, @FinishdiscussingButtonPushed, true);
            app.FinishdiscussingButton.Text = 'Finish discussing';
            app.MaxintensitySpinnerLabel = uilabel(app.UIFigure);
            app.MaxintensitySpinnerLabel.Text = 'Max intensity (%)';
            app.MaxintensitySpinner = uispinner(app.UIFigure);
            app.MaxintensitySpinner.ValueDisplayFormat = '%.0f';
            app.MaxintensitySpinner.ValueChangedFcn = createCallbackFcn(app, @MaxintensitySpinnerValueChanged, true);
            app.TilenameEditFieldLabel = uilabel(app.UIFigure);
            app.TilenameEditFieldLabel.Text = 'Tile name';
            app.TilenameEditField = uieditfield(app.UIFigure, 'text');
            app.TilenameEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = toy_dataset

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end